classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AircraftTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        InertiaGeometryTab              matlab.ui.container.Tab
        MassEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        IxxEditFieldLabel               matlab.ui.control.Label
        IxxEditField                    matlab.ui.control.NumericEditField
        IyyEditFieldLabel               matlab.ui.control.Label
        IyyEditField                    matlab.ui.control.NumericEditField
        IzzEditFieldLabel               matlab.ui.control.Label
        IzzEditField                    matlab.ui.control.NumericEditField
        IxzEditFieldLabel               matlab.ui.control.Label
        IxzEditField                    matlab.ui.control.NumericEditField
        IxyEditFieldLabel               matlab.ui.control.Label
        IxyEditField                    matlab.ui.control.NumericEditField
        IyzEditFieldLabel               matlab.ui.control.Label
        IyzEditField                    matlab.ui.control.NumericEditField
        MomentofInertiaMatrixLabel      matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        PlanformAreaEditFieldLabel      matlab.ui.control.Label
        PlanformAreaEditField           matlab.ui.control.NumericEditField
        WingSpanEditFieldLabel          matlab.ui.control.Label
        WingSpanEditField               matlab.ui.control.NumericEditField
        MeanAerodynamicChordLengthEditFieldLabel  matlab.ui.control.Label
        MeanAerodynamicChordLengthEditField  matlab.ui.control.NumericEditField
        kgm2Label                       matlab.ui.control.Label
        kgm2Label_2                     matlab.ui.control.Label
        kgm2Label_3                     matlab.ui.control.Label
        kgm2Label_4                     matlab.ui.control.Label
        kgm2Label_5                     matlab.ui.control.Label
        kgm2Label_6                     matlab.ui.control.Label
        PropulsionControlSurfacesTab    matlab.ui.container.Tab
        ElevatorUpperLimitEditFieldLabel  matlab.ui.control.Label
        ElevatorUpperLimitEditField     matlab.ui.control.NumericEditField
        ElevatorLowerLimitEditFieldLabel  matlab.ui.control.Label
        ElevatorLowerLimitEditField     matlab.ui.control.NumericEditField
        AileronUpperLimitEditFieldLabel  matlab.ui.control.Label
        AileronUpperLimitEditField      matlab.ui.control.NumericEditField
        AileronLowerLimitEditFieldLabel  matlab.ui.control.Label
        AileronLowerLimitEditField      matlab.ui.control.NumericEditField
        RudderLowerLimitEditFieldLabel  matlab.ui.control.Label
        RudderLowerLimitEditField       matlab.ui.control.NumericEditField
        RudderUpperLimitEditFieldLabel  matlab.ui.control.Label
        RudderUpperLimitEditField       matlab.ui.control.NumericEditField
        ControlSurfacesLabel            matlab.ui.control.Label
        FlapsLowerLimitEditFieldLabel   matlab.ui.control.Label
        FlapsLowerLimitEditField        matlab.ui.control.NumericEditField
        FlapsUpperLimitEditFieldLabel   matlab.ui.control.Label
        FlapsUpperLimitEditField        matlab.ui.control.NumericEditField
        degLabel                        matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        degLabel_7                      matlab.ui.control.Label
        degLabel_8                      matlab.ui.control.Label
        ElecticPanel                    matlab.ui.container.Panel
        BatteryVoltageEditFieldLabel    matlab.ui.control.Label
        BatteryVoltageEditField         matlab.ui.control.NumericEditField
        BatteryCapacityEditFieldLabel   matlab.ui.control.Label
        BatteryCapacityEditField        matlab.ui.control.NumericEditField
        mAhLabel                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        motorKVEditFieldLabel           matlab.ui.control.Label
        motorKVEditField                matlab.ui.control.NumericEditField
        rpmVLabel                       matlab.ui.control.Label
        PropulsiveDeviceButtonGroup     matlab.ui.container.ButtonGroup
        ElectricButton                  matlab.ui.control.RadioButton
        ConstantSpeedPropellerButton    matlab.ui.control.RadioButton
        TurboPropButton                 matlab.ui.control.RadioButton
        ConstantSpeedPropellerPanel     matlab.ui.container.Panel
        WLabel_CSP                      matlab.ui.control.Label
        MaximumPowerEditFieldLabel      matlab.ui.control.Label
        MaximumPowerEditField_CSP       matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField_CSP         matlab.ui.control.NumericEditField
        TurbochargeAltitudeEditFieldLabel  matlab.ui.control.Label
        TurbochargeAltitudeEditField_CSP  matlab.ui.control.NumericEditField
        ftLabel_2                       matlab.ui.control.Label
        TurbopropPanel                  matlab.ui.container.Panel
        MaximumPowerLabel               matlab.ui.control.Label
        MaximumPowerEditField_TP        matlab.ui.control.NumericEditField
        WLabel_TP                       matlab.ui.control.Label
        EfficiencyEditField_2Label      matlab.ui.control.Label
        EfficiencyEditField_TP          matlab.ui.control.NumericEditField
        AltitudeIndexEditFieldLabel     matlab.ui.control.Label
        AltitudeIndexEditField_TP       matlab.ui.control.NumericEditField
        PropellerLabel                  matlab.ui.control.Label
        CT_J2EditFieldLabel             matlab.ui.control.Label
        CT_J2EditField                  matlab.ui.control.NumericEditField
        CT_JEditFieldLabel              matlab.ui.control.Label
        CT_JEditField                   matlab.ui.control.NumericEditField
        CT_0EditFieldLabel              matlab.ui.control.Label
        CT_0EditField                   matlab.ui.control.NumericEditField
        CP_J3EditFieldLabel             matlab.ui.control.Label
        CP_J3EditField                  matlab.ui.control.NumericEditField
        CP_J2EditFieldLabel             matlab.ui.control.Label
        CP_J2EditField                  matlab.ui.control.NumericEditField
        CP_JEditFieldLabel              matlab.ui.control.Label
        CP_JEditField                   matlab.ui.control.NumericEditField
        CP_0EditFieldLabel              matlab.ui.control.Label
        CP_0EditField                   matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        mLabel_3                        matlab.ui.control.Label
        AerodynamicsTab                 matlab.ui.container.Tab
        LiftCoefficientsLabel           matlab.ui.control.Label
        DragCoefficientsLabel           matlab.ui.control.Label
        PitchMomentCoefficientsLabel    matlab.ui.control.Label
        SideForceCoefficientsLabel      matlab.ui.control.Label
        RollMomentCoefficientsLabel     matlab.ui.control.Label
        YawMomentCoefficientsLabel      matlab.ui.control.Label
        perunitMACLabel                 matlab.ui.control.Label
        C_L_0EditFieldLabel             matlab.ui.control.Label
        C_L_0EditField                  matlab.ui.control.NumericEditField
        C_L_alphaLabel                  matlab.ui.control.Label
        C_L_alphaEditField              matlab.ui.control.NumericEditField
        C_L_qEditFieldLabel             matlab.ui.control.Label
        C_L_qEditField                  matlab.ui.control.NumericEditField
        C_D_0Label                      matlab.ui.control.Label
        C_D_0EditField                  matlab.ui.control.NumericEditField
        C_m_0EditFieldLabel             matlab.ui.control.Label
        C_m_0EditField                  matlab.ui.control.NumericEditField
        C_m_qEditFieldLabel             matlab.ui.control.Label
        C_m_qEditField                  matlab.ui.control.NumericEditField
        C_m_alphaEditFieldLabel         matlab.ui.control.Label
        C_m_alphaEditField              matlab.ui.control.NumericEditField
        C_Y_0EditFieldLabel             matlab.ui.control.Label
        C_Y_0EditField                  matlab.ui.control.NumericEditField
        C_Y_pEditFieldLabel             matlab.ui.control.Label
        C_Y_pEditField                  matlab.ui.control.NumericEditField
        C_l_0EditFieldLabel             matlab.ui.control.Label
        C_l_0EditField                  matlab.ui.control.NumericEditField
        C_l_betaEditFieldLabel          matlab.ui.control.Label
        C_l_betaEditField               matlab.ui.control.NumericEditField
        C_l_dotbetaEditFieldLabel       matlab.ui.control.Label
        C_l_dotbetaEditField            matlab.ui.control.NumericEditField
        C_n_0EditFieldLabel             matlab.ui.control.Label
        C_n_0EditField                  matlab.ui.control.NumericEditField
        C_n_dotbetaEditFieldLabel       matlab.ui.control.Label
        C_n_dotbetaEditField            matlab.ui.control.NumericEditField
        C_n_betaEditFieldLabel          matlab.ui.control.Label
        C_n_betaEditField               matlab.ui.control.NumericEditField
        C_L_dotalphaEditFieldLabel      matlab.ui.control.Label
        C_L_dotalphaEditField           matlab.ui.control.NumericEditField
        C_L_deltaeEditFieldLabel        matlab.ui.control.Label
        C_L_deltaeEditField             matlab.ui.control.NumericEditField
        C_m_dotalphaEditFieldLabel      matlab.ui.control.Label
        C_m_dotalphaEditField           matlab.ui.control.NumericEditField
        C_m_deltaeEditFieldLabel        matlab.ui.control.Label
        C_m_deltaeEditField             matlab.ui.control.NumericEditField
        C_Y_rEditFieldLabel             matlab.ui.control.Label
        C_Y_rEditField                  matlab.ui.control.NumericEditField
        C_Y_deltaaEditFieldLabel        matlab.ui.control.Label
        C_Y_deltaaEditField             matlab.ui.control.NumericEditField
        C_Y_deltarEditFieldLabel        matlab.ui.control.Label
        C_Y_deltarEditField             matlab.ui.control.NumericEditField
        C_Y_dotbetaEditFieldLabel       matlab.ui.control.Label
        C_Y_dotbetaEditField            matlab.ui.control.NumericEditField
        C_l_pEditFieldLabel             matlab.ui.control.Label
        C_l_pEditField                  matlab.ui.control.NumericEditField
        C_l_rEditFieldLabel             matlab.ui.control.Label
        C_l_rEditField                  matlab.ui.control.NumericEditField
        C_l_deltaaEditFieldLabel        matlab.ui.control.Label
        C_l_deltaaEditField             matlab.ui.control.NumericEditField
        C_l_deltarEditFieldLabel        matlab.ui.control.Label
        C_l_deltarEditField             matlab.ui.control.NumericEditField
        C_n_pEditFieldLabel             matlab.ui.control.Label
        C_n_pEditField                  matlab.ui.control.NumericEditField
        C_n_rEditFieldLabel             matlab.ui.control.Label
        C_n_rEditField                  matlab.ui.control.NumericEditField
        C_n_deltaaEditFieldLabel        matlab.ui.control.Label
        C_n_deltaaEditField             matlab.ui.control.NumericEditField
        C_n_deltarEditFieldLabel        matlab.ui.control.Label
        C_n_deltarEditField             matlab.ui.control.NumericEditField
        CentreofGravityDuringMeasurementsEditFieldLabel  matlab.ui.control.Label
        CentreofGravityDuringMeasurementsEditField  matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        C_Y_betaEditFieldLabel          matlab.ui.control.Label
        C_Y_betaEditField               matlab.ui.control.NumericEditField
        C_L_deltafEditFieldLabel        matlab.ui.control.Label
        C_L_deltafEditField             matlab.ui.control.NumericEditField
        C_m_deltafEditField_2Label      matlab.ui.control.Label
        C_m_deltafEditField             matlab.ui.control.NumericEditField
        OptionsTab                      matlab.ui.container.Tab
        loadaircraftButton              matlab.ui.control.Button
        saveaircraftButton              matlab.ui.control.Button
        AircraftNameEditFieldLabel      matlab.ui.control.Label
        AircraftNameEditField           matlab.ui.control.EditField
        AircraftDescriptionTextAreaLabel  matlab.ui.control.Label
        AircraftDescriptionTextArea     matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        ProfileImageFolderButton        matlab.ui.control.Button
        AircraftClassDropDownLabel      matlab.ui.control.Label
        AircraftClassDropDown           matlab.ui.control.DropDown
        EnvironmentTab                  matlab.ui.container.Tab
        gravityEditFieldLabel           matlab.ui.control.Label
        gravityEditField                matlab.ui.control.NumericEditField
        WindSpeedLabel                  matlab.ui.control.Label
        WindSpeedEditField              matlab.ui.control.NumericEditField
        SeaLevelDensityEditFieldLabel   matlab.ui.control.Label
        SeaLevelDensityEditField        matlab.ui.control.NumericEditField
        SeaLevelTemperatureEditFieldLabel  matlab.ui.control.Label
        SeaLevelTemperatureEditField    matlab.ui.control.NumericEditField
        LapseRateEditFieldLabel         matlab.ui.control.Label
        LapseRateEditField              matlab.ui.control.NumericEditField
        UniversalGasConstantEditFieldLabel  matlab.ui.control.Label
        UniversalGasConstantEditField   matlab.ui.control.NumericEditField
        WindBearingEditFieldLabel       matlab.ui.control.Label
        WindBearingEditField            matlab.ui.control.NumericEditField
        SealLevelPressureEditFieldLabel  matlab.ui.control.Label
        SealLevelPressureEditField      matlab.ui.control.NumericEditField
        ktsLabel                        matlab.ui.control.Label
        degTRUELabel                    matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        PaLabel                         matlab.ui.control.Label
        NkgLabel                        matlab.ui.control.Label
        OperationTab                    matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        ManualControlsTab               matlab.ui.container.Tab
        ChangeControlDataButtonGroup    matlab.ui.container.ButtonGroup
        ThrustButton                    matlab.ui.control.RadioButton
        ElevatorButton                  matlab.ui.control.RadioButton
        AileronButton                   matlab.ui.control.RadioButton
        RudderButton                    matlab.ui.control.RadioButton
        FlapsButton                     matlab.ui.control.RadioButton
        YminSpinnerLabel                matlab.ui.control.Label
        YminSpinner                     matlab.ui.control.Spinner
        YmaxSpinnerLabel                matlab.ui.control.Label
        YmaxSpinner                     matlab.ui.control.Spinner
        InitialControlValueEditFieldLabel  matlab.ui.control.Label
        InitialControlValueEditField    matlab.ui.control.NumericEditField
        FinalControlValueEditFieldLabel  matlab.ui.control.Label
        FinalControlValueEditField      matlab.ui.control.NumericEditField
        AddPointButton                  matlab.ui.control.Button
        DeletePointButton               matlab.ui.control.Button
        SelectPointDropDownLabel        matlab.ui.control.Label
        SelectPointDropDown             matlab.ui.control.DropDown
        XminSpinnerLabel                matlab.ui.control.Label
        XminSpinner                     matlab.ui.control.Spinner
        XmaxSpinnerLabel                matlab.ui.control.Label
        XmaxSpinner                     matlab.ui.control.Spinner
        LoadControlsDropDownLabel       matlab.ui.control.Label
        LoadControlsDropDown            matlab.ui.control.DropDown
        SaveControlsButton              matlab.ui.control.Button
        ControlsFolderButton            matlab.ui.control.Button
        MovingAverageWindowsEditFieldLabel  matlab.ui.control.Label
        MovingAverageWindowsEditField   matlab.ui.control.NumericEditField
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        ControlFileNameEditFieldLabel   matlab.ui.control.Label
        ControlFileNameEditField        matlab.ui.control.EditField
        UIAxes_ControlDesigner          matlab.ui.control.UIAxes
        TrimmedConditionsFlightDetailsTab  matlab.ui.container.Tab
        TrimmedAltitudemEditFieldLabel  matlab.ui.control.Label
        TrimmedAltitudemEditField       matlab.ui.control.NumericEditField
        TrimmedBearingEditFieldLabel    matlab.ui.control.Label
        TrimmedBearingEditField         matlab.ui.control.NumericEditField
        TrimmedAirspeedLabel            matlab.ui.control.Label
        TrimmedAirspeedEditField        matlab.ui.control.NumericEditField
        TrimmedSideslipAngledegEditFieldLabel  matlab.ui.control.Label
        TrimmedSideslipAngledegEditField  matlab.ui.control.NumericEditField
        CentreofGravityPositionEditFieldLabel  matlab.ui.control.Label
        CentreofGravityPositionEditField  matlab.ui.control.NumericEditField
        FlightPhaseCategoryDropDownLabel  matlab.ui.control.Label
        FlightPhaseCategoryDropDown     matlab.ui.control.DropDown
        InitialLatitudedegEditFieldLabel  matlab.ui.control.Label
        InitialLatitudedegEditField     matlab.ui.control.NumericEditField
        InitialLongitudedegEditFieldLabel  matlab.ui.control.Label
        InitialLongitudedegEditField    matlab.ui.control.NumericEditField
        TrimmedConditionsLabel          matlab.ui.control.Label
        ktsLabel_2                      matlab.ui.control.Label
        degTRUELabel_2                  matlab.ui.control.Label
        ftLabel                         matlab.ui.control.Label
        degLabel_9                      matlab.ui.control.Label
        degNorthLabel                   matlab.ui.control.Label
        degEastLabel                    matlab.ui.control.Label
        perunitMACLabel_2               matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        RUNButton                       matlab.ui.control.Button
        TimeSpanLabel                   matlab.ui.control.Label
        TimeSpanEditField               matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        PlotTrajectoryRequiresthemappingtoolboxCheckBox  matlab.ui.control.CheckBox
        AnimateRequirestheaerospacetoolboxCheckBox  matlab.ui.control.CheckBox
        TrajectoryDotsEditFieldLabel    matlab.ui.control.Label
        TrajectoryDotsEditField         matlab.ui.control.NumericEditField
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        secLabel                        matlab.ui.control.Label
        secLabel_2                      matlab.ui.control.Label
        secLabel_3                      matlab.ui.control.Label
        ImportkmlPathButton             matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        TransientResponseTab            matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        StatesTab                       matlab.ui.container.Tab
        uCheckBox                       matlab.ui.control.CheckBox
        vCheckBox                       matlab.ui.control.CheckBox
        wCheckBox                       matlab.ui.control.CheckBox
        pCheckBox                       matlab.ui.control.CheckBox
        qCheckBox                       matlab.ui.control.CheckBox
        rCheckBox                       matlab.ui.control.CheckBox
        phiCheckBox                     matlab.ui.control.CheckBox
        thetaCheckBox                   matlab.ui.control.CheckBox
        psiCheckBox                     matlab.ui.control.CheckBox
        xCheckBox                       matlab.ui.control.CheckBox
        yCheckBox                       matlab.ui.control.CheckBox
        zCheckBox                       matlab.ui.control.CheckBox
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        AerodynamicFrameTab             matlab.ui.container.Tab
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        UIAxes_15                       matlab.ui.control.UIAxes
        ControlsTab                     matlab.ui.container.Tab
        UIAxes_16                       matlab.ui.control.UIAxes
        UIAxes_17                       matlab.ui.control.UIAxes
        UIAxes_18                       matlab.ui.control.UIAxes
        UIAxes_19                       matlab.ui.control.UIAxes
        UIAxes_20                       matlab.ui.control.UIAxes
        LinearAnalysisTab               matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        DynamicMatriciesTab             matlab.ui.container.Tab
        UITableA                        matlab.ui.control.Table
        UITableB                        matlab.ui.control.Table
        EigenAnalysisTab                matlab.ui.container.Tab
        UITableE1                       matlab.ui.control.Table
        UITableE2                       matlab.ui.control.Table
        UIAxes_21                       matlab.ui.control.UIAxes
        ArgandDiagramsTab_2             matlab.ui.container.Tab
        ModeofMotionButtonGroup         matlab.ui.container.ButtonGroup
        SPButton                        matlab.ui.control.RadioButton
        LPButton                        matlab.ui.control.RadioButton
        RollButton_2                    matlab.ui.control.RadioButton
        DutchRollButton_2               matlab.ui.control.RadioButton
        AltcondivButton                 matlab.ui.control.RadioButton
        SpiralRollButton                matlab.ui.control.RadioButton
        UITable_AD                      matlab.ui.control.Table
        UIAxes_22                       matlab.ui.control.UIAxes
        MilitarySpecificationsTab       matlab.ui.container.Tab
        TabGroup9                       matlab.ui.container.TabGroup
        SPWnvszetaTab                   matlab.ui.container.Tab
        UIAxes_30                       matlab.ui.control.UIAxes
        SPWnvsdndalphaTab               matlab.ui.container.Tab
        UIAxes_31                       matlab.ui.control.UIAxes
        DRTab                           matlab.ui.container.Tab
        UIAxes_32                       matlab.ui.control.UIAxes
        OptionsTab_3                    matlab.ui.container.Tab
        savetrajectoryasKMLfileLabel    matlab.ui.control.Label
        KMLfilenameEditFieldLabel       matlab.ui.control.Label
        KMLfilenameEditField            matlab.ui.control.EditField
        SaveKMLfileButton               matlab.ui.control.Button
        OptionsTab_2                    matlab.ui.container.Tab
        CompareResultsupcomingLabel     matlab.ui.control.Label
        LoadSaveAllDataexceptresultsLabel  matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        SaveFileButton                  matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CTRL
        CTRL_k
        AIRCRAFT
        OPERATION
        ENVIRONMENT
        SIMULATION
        
        TRANSIENT
        LINEAR
        TRAJECTORY
        
        DIR
        
    end
    
    methods (Access = private)

 
function aircraftStructure(app)

    app.AIRCRAFT.x_cg_0         = app.CentreofGravityDuringMeasurementsEditField.Value;
    
    app.AIRCRAFT.Inertia.m      = app.MassEditField.Value;
    app.AIRCRAFT.Inertia.Ixx    = app.IxxEditField.Value;
    app.AIRCRAFT.Inertia.Iyy    = app.IyyEditField.Value;
    app.AIRCRAFT.Inertia.Izz    = app.IzzEditField.Value;
    app.AIRCRAFT.Inertia.Ixy    = app.IxyEditField.Value;
    app.AIRCRAFT.Inertia.Ixz    = app.IxzEditField.Value;
    app.AIRCRAFT.Inertia.Iyz    = app.IyzEditField.Value;
    
    app.AIRCRAFT.Geom.S         = app.PlanformAreaEditField.Value;
    app.AIRCRAFT.Geom.b         = app.WingSpanEditField.Value;
    app.AIRCRAFT.Geom.c         = app.MeanAerodynamicChordLengthEditField.Value;
    
    app.AIRCRAFT.Prop.object    = app.PropulsiveDeviceButtonGroup.SelectedObject;
    
    if strcmp(app.AIRCRAFT.Prop.object.Text, 'Electric')
        app.AIRCRAFT.Prop.Vtotal    = app.BatteryVoltageEditField.Value;
        app.AIRCRAFT.Prop.capacity  = app.BatteryCapacityEditField.Value;
        app.AIRCRAFT.Prop.motorKV   = app.motorKVEditField.Value;
    elseif strcmp(app.AIRCRAFT.Prop.object.Text, 'Constant Speed Propeller')
        app.AIRCRAFT.Prop.P_max     = app.MaximumPowerEditField_CSP.Value;
        app.AIRCRAFT.Prop.eta       = app.EfficiencyEditField_CSP.Value;
        app.AIRCRAFT.Prop.turboAlt  = app.TurbochargeAltitudeEditField_CSP.Value;
    elseif strcmp(app.AIRCRAFT.Prop.object.Text, 'Turbo-Prop')
        app.AIRCRAFT.Prop.P_max     = app.MaximumPowerEditField_TP.Value;
        app.AIRCRAFT.Prop.eta       = app.EfficiencyEditField_TP.Value;
        app.AIRCRAFT.Prop.altIndex  = app.AltitudeIndexEditField_TP.Value;
    end
    
    app.AIRCRAFT.Prop.CT        = [app.CT_0EditField.Value app.CT_JEditField.Value app.CT_J2EditField.Value 0];
    app.AIRCRAFT.Prop.CP        = [app.CP_0EditField.Value app.CP_JEditField.Value app.CP_J2EditField.Value app.CP_J3EditField.Value];
    app.AIRCRAFT.Prop.diameter  = app.DiameterEditField.Value;
    
    app.AIRCRAFT.CtrlLim.Lwr    = [0;
                                app.ElevatorLowerLimitEditField.Value;
                                app.AileronLowerLimitEditField.Value;
                                app.RudderLowerLimitEditField.Value;
                                app.FlapsLowerLimitEditField.Value];
    app.AIRCRAFT.CtrlLim.Upr    = [1;
                                app.ElevatorUpperLimitEditField.Value;
                                app.AileronUpperLimitEditField.Value;
                                app.RudderUpperLimitEditField.Value;
                                app.FlapsUpperLimitEditField.Value];
    
    app.AIRCRAFT.Aero.CDo       = app.C_D_0EditField.Value;
    app.AIRCRAFT.Aero.k         = app.kEditField.Value;
     
    app.AIRCRAFT.Aero.CLo       = app.C_L_0EditField.Value;
    app.AIRCRAFT.Aero.CLa       = app.C_L_alphaEditField.Value;
    app.AIRCRAFT.Aero.CLad      = app.C_L_dotalphaEditField.Value;
    app.AIRCRAFT.Aero.CLq       = app.C_L_qEditField.Value;
    app.AIRCRAFT.Aero.CLde      = app.C_L_deltaeEditField.Value;
    app.AIRCRAFT.Aero.CLdf      = app.C_L_deltafEditField.Value;
    
    app.AIRCRAFT.Aero.Cmo       = app.C_m_0EditField.Value;
    app.AIRCRAFT.Aero.Cma       = app.C_m_alphaEditField.Value;
    app.AIRCRAFT.Aero.Cmad      = app.C_m_dotalphaEditField.Value;
    app.AIRCRAFT.Aero.Cmq       = app.C_m_qEditField.Value;
    app.AIRCRAFT.Aero.Cmde      = app.C_m_deltaeEditField.Value;
    app.AIRCRAFT.Aero.Cmdf      = app.C_m_deltafEditField.Value;
    
    app.AIRCRAFT.Aero.CYo       = app.C_Y_0EditField.Value;
    app.AIRCRAFT.Aero.CYb       = app.C_Y_betaEditField.Value;
    app.AIRCRAFT.Aero.CYbd      = app.C_Y_dotbetaEditField.Value;
    app.AIRCRAFT.Aero.CYp       = app.C_Y_pEditField.Value;
    app.AIRCRAFT.Aero.CYr       = app.C_Y_rEditField.Value;
    app.AIRCRAFT.Aero.CYda      = app.C_Y_deltaaEditField.Value;
    app.AIRCRAFT.Aero.CYdr      = app.C_Y_deltarEditField.Value;
    
    app.AIRCRAFT.Aero.Clo       = app.C_l_0EditField.Value;
    app.AIRCRAFT.Aero.Clb       = app.C_l_betaEditField.Value;
    app.AIRCRAFT.Aero.Clbd      = app.C_l_dotbetaEditField.Value;
    app.AIRCRAFT.Aero.Clp       = app.C_l_pEditField.Value;
    app.AIRCRAFT.Aero.Clr       = app.C_l_rEditField.Value;
    app.AIRCRAFT.Aero.Clda      = app.C_l_deltaaEditField.Value;
    app.AIRCRAFT.Aero.Cldr      = app.C_l_deltarEditField.Value;
    
    app.AIRCRAFT.Aero.Cno       = app.C_n_0EditField.Value;
    app.AIRCRAFT.Aero.Cnb       = app.C_n_betaEditField.Value;
    app.AIRCRAFT.Aero.Cnbd      = app.C_n_dotbetaEditField.Value;
    app.AIRCRAFT.Aero.Cnp       = app.C_n_pEditField.Value;
    app.AIRCRAFT.Aero.Cnr       = app.C_n_rEditField.Value;
    app.AIRCRAFT.Aero.Cnda      = app.C_n_deltaaEditField.Value;
    app.AIRCRAFT.Aero.Cndr      = app.C_n_deltarEditField.Value;
    
    app.AIRCRAFT.Options.name   = app.AircraftNameEditField.Value;
    app.AIRCRAFT.Options.description = app.AircraftDescriptionTextArea.Value;
    app.AIRCRAFT.Options.profile = app.Image.ImageSource;
    
end


function aircraftEditFields(app)

    app.CentreofGravityDuringMeasurementsEditField.Value = app.AIRCRAFT.x_cg_0;
    
    app.MassEditField.Value                 = app.AIRCRAFT.Inertia.m;
    app.IxxEditField.Value                  = app.AIRCRAFT.Inertia.Ixx;
    app.IyyEditField.Value                  = app.AIRCRAFT.Inertia.Iyy;
    app.IzzEditField.Value                  = app.AIRCRAFT.Inertia.Izz;
    app.IxyEditField.Value                  = app.AIRCRAFT.Inertia.Ixy;
    app.IxzEditField.Value                  = app.AIRCRAFT.Inertia.Ixz;
    app.IyzEditField.Value                  = app.AIRCRAFT.Inertia.Iyz;
    
    app.PlanformAreaEditField.Value         = app.AIRCRAFT.Geom.S;
    app.WingSpanEditField.Value             = app.AIRCRAFT.Geom.b;
    app.MeanAerodynamicChordLengthEditField.Value= app.AIRCRAFT.Geom.c;
    
    if strcmp(app.AIRCRAFT.Prop.object.Text, 'Electric')
        app.ElectricButton.Value                = 1;
        app.BatteryVoltageEditField.Value       = app.AIRCRAFT.Prop.Vtotal;
        app.BatteryCapacityEditField.Value      = app.AIRCRAFT.Prop.capacity;
        app.motorKVEditField.Value              = app.AIRCRAFT.Prop.motorKV;
    elseif strcmp(app.AIRCRAFT.Prop.object.Text, 'Constant Speed Propeller')
        app.ConstantSpeedPropellerButton.Value  = 1;
        app.MaximumPowerEditField_CSP.Value     = app.AIRCRAFT.Prop.P_max;
        app.EfficiencyEditField_CSP.Value       = app.AIRCRAFT.Prop.eta;
        app.TurbochargeAltitudeEditField_CSP.Value= app.AIRCRAFT.Prop.turboAlt;
    elseif strcmp(app.AIRCRAFT.Prop.object.Text, 'Turbo-Prop')
        app.TurboPropButton.Value               = 1;
        app.MaximumPowerEditField_TP.Value      = app.AIRCRAFT.Prop.P_max;
        app.EfficiencyEditField_TP.Value        = app.AIRCRAFT.Prop.eta;
        app.AltitudeIndexEditField_TP.Value     = app.AIRCRAFT.Prop.altIndex;
    end
    
    app.CT_J2EditField.Value                = app.AIRCRAFT.Prop.CT(3);
    app.CT_JEditField.Value                 = app.AIRCRAFT.Prop.CT(2);
    app.CT_0EditField.Value                 = app.AIRCRAFT.Prop.CT(1);
    app.CP_J3EditField.Value                = app.AIRCRAFT.Prop.CP(4);
    app.CP_J2EditField.Value                = app.AIRCRAFT.Prop.CP(3);
    app.CP_JEditField.Value                 = app.AIRCRAFT.Prop.CP(2);
    app.CP_0EditField.Value                 = app.AIRCRAFT.Prop.CP(1);    
    app.DiameterEditField.Value             = app.AIRCRAFT.Prop.diameter;
    
    app.ElevatorUpperLimitEditField.Value   = app.AIRCRAFT.CtrlLim.Upr(2);
    app.ElevatorLowerLimitEditField.Value   = app.AIRCRAFT.CtrlLim.Lwr(2);
    app.AileronUpperLimitEditField.Value    = app.AIRCRAFT.CtrlLim.Upr(3);
    app.AileronLowerLimitEditField.Value    = app.AIRCRAFT.CtrlLim.Lwr(3);
    app.RudderUpperLimitEditField.Value     = app.AIRCRAFT.CtrlLim.Upr(4);
    app.RudderLowerLimitEditField.Value     = app.AIRCRAFT.CtrlLim.Lwr(4);
    app.FlapsUpperLimitEditField.Value      = app.AIRCRAFT.CtrlLim.Upr(5);
    app.FlapsLowerLimitEditField.Value      = app.AIRCRAFT.CtrlLim.Lwr(5);
    
    app.C_D_0EditField.Value                = app.AIRCRAFT.Aero.CDo;
    app.kEditField.Value                    = app.AIRCRAFT.Aero.k;
    
    app.C_L_0EditField.Value                = app.AIRCRAFT.Aero.CLo;
    app.C_L_alphaEditField.Value            = app.AIRCRAFT.Aero.CLa;
    app.C_L_dotalphaEditField.Value         = app.AIRCRAFT.Aero.CLad;
    app.C_L_qEditField.Value                = app.AIRCRAFT.Aero.CLq;
    app.C_L_deltaeEditField.Value           = app.AIRCRAFT.Aero.CLde;
    app.C_L_deltafEditField.Value           = app.AIRCRAFT.Aero.CLdf;
    
    app.C_m_0EditField.Value                = app.AIRCRAFT.Aero.Cmo;
    app.C_m_alphaEditField.Value            = app.AIRCRAFT.Aero.Cma;
    app.C_m_dotalphaEditField.Value         = app.AIRCRAFT.Aero.Cmad;
    app.C_m_qEditField.Value                = app.AIRCRAFT.Aero.Cmq;
    app.C_m_deltaeEditField.Value           = app.AIRCRAFT.Aero.Cmde;
    app.C_m_deltafEditField.Value           = app.AIRCRAFT.Aero.Cmdf;
    
    app.C_Y_0EditField.Value                = app.AIRCRAFT.Aero.CYo;
    app.C_Y_betaEditField.Value             = app.AIRCRAFT.Aero.CYb;
    app.C_Y_dotbetaEditField.Value          = app.AIRCRAFT.Aero.CYbd;
    app.C_Y_pEditField.Value                = app.AIRCRAFT.Aero.CYp;
    app.C_Y_rEditField.Value                = app.AIRCRAFT.Aero.CYr;
    app.C_Y_deltaaEditField.Value           = app.AIRCRAFT.Aero.CYda;
    app.C_Y_deltarEditField.Value           = app.AIRCRAFT.Aero.CYdr;
    
    app.C_l_0EditField.Value                = app.AIRCRAFT.Aero.Clo;
    app.C_l_betaEditField.Value             = app.AIRCRAFT.Aero.Clb;
    app.C_l_dotbetaEditField.Value          = app.AIRCRAFT.Aero.Clbd;
    app.C_l_pEditField.Value                = app.AIRCRAFT.Aero.Clp;
    app.C_l_rEditField.Value                = app.AIRCRAFT.Aero.Clr;
    app.C_l_deltaaEditField.Value           = app.AIRCRAFT.Aero.Clda;
    app.C_l_deltarEditField.Value           = app.AIRCRAFT.Aero.Cldr;
    
    app.C_n_0EditField.Value                = app.AIRCRAFT.Aero.Cno;
    app.C_n_betaEditField.Value             = app.AIRCRAFT.Aero.Cnb;
    app.C_n_dotbetaEditField.Value          = app.AIRCRAFT.Aero.Cnbd;
    app.C_n_pEditField.Value                = app.AIRCRAFT.Aero.Cnp;
    app.C_n_rEditField.Value                = app.AIRCRAFT.Aero.Cnr;
    app.C_n_deltaaEditField.Value           = app.AIRCRAFT.Aero.Cnda;
    app.C_n_deltarEditField.Value           = app.AIRCRAFT.Aero.Cnr;
    
    app.AircraftNameEditField.Value         = app.AIRCRAFT.Options.name;
    app.AircraftDescriptionTextArea.Value   = app.AIRCRAFT.Options.description;
    app.Image.ImageSource                   = app.AIRCRAFT.Options.profile;

end


function parameters = parametersStructure(app)

    aircraftStructure(app);
    
    operation.Trim.airspeed             = app.TrimmedAirspeedEditField.Value;
    operation.Trim.bearing              = app.TrimmedBearingEditField.Value;
    operation.Trim.altitude             = app.TrimmedAltitudemEditField.Value;
    operation.Trim.beta                 = app.TrimmedSideslipAngledegEditField.Value;
    operation.latitude0                 = app.InitialLatitudedegEditField.Value;
    operation.longitude0                = app.InitialLongitudedegEditField.Value;
    operation.x_cg                      = app.CentreofGravityPositionEditField.Value;
    % do not edit field with this one
    operation.manualControls            = [app.CTRL(1).U_Ave';
                                           app.CTRL(2).U_Ave';
                                           app.CTRL(3).U_Ave';
                                           app.CTRL(4).U_Ave';
                                           app.CTRL(5).U_Ave'];
    
    environment.gravity                 = app.gravityEditField.Value;
    environment.Wind.speed              = app.WindSpeedEditField.Value;
    environment.Wind.bearing            = app.WindBearingEditField.Value;
    environment.Sealevel.density        = app.SeaLevelDensityEditField.Value;
    environment.Sealevel.temperature    = app.SeaLevelTemperatureEditField.Value;
    environment.Sealevel.pressure       = app.SealLevelPressureEditField.Value;
    environment.lapseRate               = app.LapseRateEditField.Value;
    environment.universalGasConst       = app.UniversalGasConstantEditField.Value;
    
    simulation.timeSpan                 = app.TimeSpanEditField.Value;
    simulation.timeStep                 = app.TimeStepEditField.Value;
    
    
    % Package output
    parameters.aircraft     = app.AIRCRAFT;
    parameters.operation    = operation;
    parameters.environment  = environment;
    parameters.simulation   = simulation;
    
    app.OPERATION   = operation;
    app.ENVIRONMENT = environment;
    app.SIMULATION  = simulation;
end


function parametersEditFields(app, parameters)

    % Unpack
    app.AIRCRAFT= parameters.aircraft;
    operation   = parameters.operation;
    environment = parameters.environment;
    simulation  = parameters.simulation;
    
    
    aircraftEditFields(app);
    
    app.TrimmedAirspeedEditField.Value       = operation.Trim.airspeed;
    app.TrimmedBearingEditField.Value= operation.Trim.bearing;
    app.TrimmedAltitudemEditField.Value      = app.TrimmedAltitudemEditField.Value;
    app.TrimmedSideslipAngledegEditField.Value= operation.Trim.beta;
    app.InitialLatitudedegEditField.Value   = operation.latitude0;
    app.InitialLongitudedegEditField.Value  = operation.longitude0;
    app.CentreofGravityPositionEditField.Value= operation.x_cg;
    
    app.gravityEditField.Value              = environment.gravity;
    app.WindSpeedEditField.Value            = environment.Wind.speed;
    app.WindBearingEditField.Value          = environment.Wind.bearing;
    app.SeaLevelDensityEditField.Value      = environment.Sealevel.density;
    app.SeaLevelTemperatureEditField.Value  = environment.Sealevel.temperature;
    app.SealLevelPressureEditField.Value    = environment.Sealevel.pressure;
    app.LapseRateEditField.Value            = environment.lapseRate;
    app.UniversalGasConstantEditField.Value = environment.universalGasConst;
    
    app.TimeSpanEditField.Value             = simulation.timeSpan;
    app.TimeStepEditField.Value             = simulation.timeStep;

end


function Results_TransientResponse(app, Transient)
    
    app.TRANSIENT = Transient;
    
    %% Unpack
    T       = Transient.T;
    Xlin    = Transient.X_lin;
    Xnl     = Transient.X_nl;
    X_dot_nl= Transient.X_dot_nl;
    U       = Transient.U;
    
    % Units
    deg = 180/pi;                   % rad to deg
    kts = 1.944;                    % m/s to knots
    ft = 3.2808399;                 % m to ft
    
    %% Unpack Input
    
    VT      = [Xlin(13,:); Xnl(13,:)]*kts;
    beta    = [Xlin(14,:); Xnl(14,:)]*deg;
    alpha   = [Xlin(15,:); Xnl(15,:)]*deg;
    
    
    delta_T = U(1,:);
    delta_e = U(2,:)*deg;
    delta_a = U(3,:)*deg;
    delta_r = U(4,:)*deg;
    delta_f = U(5,:)*deg;
    
    updateTransient(app);
    
    % Aerodynamic Speeds
    %VT
    cla(app.UIAxes_13)
    hold(app.UIAxes_13, "on")
    plot(app.UIAxes_13, T, VT(2,:), "Color", "b", "LineStyle", "-" , "LineWidth", 1.5);
    grid(app.UIAxes_13, "on")
    ylim(app.UIAxes_13, [floor(min(VT, [], "all")-0.01), ceil(max(VT, [], "all")+0.01)])
    app.UIAxes_13.YLabel.String = "True Airspeed [kts]";
    hold(app.UIAxes_13, "off")
    % beta
    cla(app.UIAxes_14)
    hold(app.UIAxes_14, "on")
    plot(app.UIAxes_14, T, beta(2,:), "Color", "b", "LineStyle", "-" , "LineWidth", 1.5);
    grid(app.UIAxes_14, "on")
    ylim(app.UIAxes_14, [floor(min(beta, [], "all")-0.01), ceil(max(beta, [], "all")+0.01)])
    app.UIAxes_14.YLabel.String = "Sideslip Angle \beta [deg]";
    hold(app.UIAxes_14, "off")
    % alpha
    cla(app.UIAxes_15)
    hold(app.UIAxes_15, "on")
    plot(app.UIAxes_15, T, alpha(2,:), "Color", "b", "LineStyle", "-" , "LineWidth", 1.5);
    grid(app.UIAxes_15, "on")
    ylim(app.UIAxes_15, [floor(min(alpha, [], "all")-0.01), ceil(max(alpha, [], "all")+0.01)])
    app.UIAxes_15.YLabel.String = "Angle of Attack \alpha [deg]";
    app.UIAxes_15.XLabel.String = "Time";
    hold(app.UIAxes_15, "off")
    
    % Controls
    % Thrust
    plot(app.UIAxes_16, T, delta_T, "r", "LineWidth", 1.5);
    grid(app.UIAxes_16, "on")
    ylim(app.UIAxes_16, [floor(min(delta_T, [], "all")-0.01), ceil(max(delta_T, [], "all")+0.01)])
    app.UIAxes_16.YLabel.String = "Thrust \delta_T";
    % elevator
    plot(app.UIAxes_17, T, delta_e, "g", "LineWidth", 1.5);
    grid(app.UIAxes_17, "on")
    ylim(app.UIAxes_17, [floor(min(delta_e, [], "all")-0.01), ceil(max(delta_e, [], "all")+0.01)])
    app.UIAxes_17.YLabel.String = "Elevator Deflection \delta_e [deg]";
    % aileron
    plot(app.UIAxes_18, T, delta_a, "c", "LineWidth", 1.5);
    grid(app.UIAxes_18, "on")
    ylim(app.UIAxes_18, [floor(min(delta_a, [], "all")-0.01), ceil(max(delta_a, [], "all")+0.01)])
    app.UIAxes_18.YLabel.String = "Aileron \delta_a [deg]";
    % rudder
    plot(app.UIAxes_19, T, delta_r, "m", "LineWidth", 1.5);
    grid(app.UIAxes_19, "on")
    ylim(app.UIAxes_19, [floor(min(delta_r, [], "all")-0.01), ceil(max(delta_r, [], "all")+0.01)])
    app.UIAxes_19.YLabel.String = "Rudder \delta_e [deg]";
    app.UIAxes_19.XLabel.String = "Time";
    % flaps
    plot(app.UIAxes_20, T, delta_f, "b", "LineWidth", 1.5);
    grid(app.UIAxes_20, "on")
    ylim(app.UIAxes_20, [floor(min(delta_f, [], "all")), ceil(max(delta_f, [], "all")+0.01)])
    app.UIAxes_20.YLabel.String = "Flaps \delta_f [deg]";
    app.UIAxes_20.XLabel.String = "Time";
    
    %% TRAJECTORY FIGURE
    
    geo = Xnl(end-2:end, :);
    
    app.TRAJECTORY.kml_array = [geo(1:2,:); -Xnl(12,:)];
    
    kml_array = app.TRAJECTORY.kml_array;
    save('savedFiles/KML_temp', 'kml_array');
    
    % plot dots to indicate time passed
    k = 0;
    for i = 1:length(T)
        if mod(T(i), app.TrajectoryDotsEditField.Value) == 0
            k = k+1;
            geo_dotted(:, k) = app.TRAJECTORY.kml_array(:,i);
        end
    end
    
    % app.TRAJECTORY.g = geoaxes("Basemap","satellite"); 
    
    if app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Value == 1
        hold(app.TRAJECTORY.globefig, "on")
        geoplot3(app.TRAJECTORY.globefig, app.TRAJECTORY.kml_array(1,:), app.TRAJECTORY.kml_array(2,:), app.TRAJECTORY.kml_array(3,:),...
                 'r', "LineWidth", 1.5);
        geoplot3(app.TRAJECTORY.globefig, geo_dotted(1,:), geo_dotted(2,:), geo_dotted(3, :), 'or', "MarkerSize", 6, "LineWidth", 1.5);
        hold(app.TRAJECTORY.globefig, "off")
    end
    
    % Animation figure
    if app.AnimateRequirestheaerospacetoolboxCheckBox.Value == 1
        modelName = app.ModelDropDown.Value;
        flashyGUI(T, Xnl, X_dot_nl, modelName);
    end

end


function Results_LinearAnalysis(app, LA)
    
    app.LINEAR = LA;
    
    % linear Tables
    app.UITableA.Data = LA.A;
    app.UITableB.Data = LA.B;
    app.UITableE1.Data = [LA.eigValues([3 5 7 8 10 11])];
    app.UITableE2.Data = [LA.natFreq([3 5 7 8 10 11]) LA.dampFactor([3 5 7 8 10 11])];
    sigma = real(LA.eigValues);
    omega_d = imag(LA.eigValues);
    
    % Eigen Values Plot
    fig = app.UIAxes_21;
    cla(fig);
    
    semilogx(fig, sigma(3:11), omega_d(3:11), "ok", "MarkerSize", 6, "LineWidth", 1.5)
    grid(fig, "on");
    
    updateLinear(app);
     
end


function Results_MilitarySpecifications(app, MilSpec)

    cla(app.UIAxes_30);
    cla(app.UIAxes_31);
    cla(app.UIAxes_32);
    
    % unpack
    dn_dalpha   = MilSpec.dn_dalpha;
    Wn          = MilSpec.natFreq;
    zeta        = MilSpec.dampRatio;
    Class       = MilSpec.Class;
    
    Wn_SP	= Wn(3:4);
    Wn_LP	= Wn(5:6);
    Wn_Alt  = Wn(10);
    
    Wn_Roll = Wn(7);
    Wn_DR   = Wn(8:9);
    Wn_SR   = Wn(11);
    
    zeta_SP	= zeta(3:4);
    zeta_LP	= zeta(5:6);
    zeta_Alt= zeta(10);
    
    zeta_Roll=zeta(7);
    zeta_DR = zeta(8:9);
    zeta_SR = zeta(11);
    
    if strcmp(app.AircraftClassDropDown.Value, "I")
        classVal = 1;
    elseif strcmp(app.AircraftClassDropDown.Value, "II")
        classVal = 2;
    elseif strcmp(app.AircraftClassDropDown.Value, "III")
        classVal = 3;
    elseif strcmp(app.AircraftClassDropDown.Value, "IV")
        classVal = 4;
    end
    
    if strcmp(app.FlightPhaseCategoryDropDown.Value, "A")
        catVal = 1;
    elseif strcmp(app.FlightPhaseCategoryDropDown.Value, "B")
        catVal = 2;
    elseif strcmp(app.FlightPhaseCategoryDropDown.Value, "C")
        catVal = 3;
    end
    
    for j = 1:3
        scaler(1, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).upr.k;
        exponent(1, j) = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).upr.n;
        ylimit(1, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).upr.y;
        xlimit(1, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lft.x;
    
        scaler(2, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lwr.k;
        exponent(2, j) = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lwr.n;
        ylimit(2, j)   = Class(classVal).Cat(catVal).SP.Dndalpha.lvl(j).lwr.y;
    
        wd_z(j).region = Class(classVal).Cat(catVal).SP.dampRatio.lvl(j).region;
    
        DR_zeta(j)      = Class(classVal).Cat(catVal).DR.lvl(j).zeta;
        DR_zetaWn(j)    = Class(classVal).Cat(catVal).DR.lvl(j).zetaWn;
        DR_Wn(j)      = Class(classVal).Cat(catVal).DR.lvl(j).Wn;
    end
    
    horz_points = logspace(min(0, floor(min(log(dn_dalpha)/log(10)))), ceil(max(log(dn_dalpha)/log(10))));
    
    for i = 1:2
        for j = 1:length(scaler(1,:))
            boundary(i, j).dndalpha = horz_points(horz_points > xlimit(1, j));
    
            boundary(i, j).natFreq = scaler(i, j)*(boundary(i, j).dndalpha.^exponent(i, j));
    
            boundary(i, j).natFreq(boundary(i, j).natFreq > ylimit(1, j)) = ylimit(1, j);
            boundary(i, j).natFreq(boundary(i, j).natFreq < ylimit(2, j)) = ylimit(2, j);
        end
    end
    
    
    %% SP wn/zeta
    semilogx(app.UIAxes_30, zeta_SP(1), Wn_SP(1), 'or',"MarkerSize", 6,'LineWidth', 1.5)
    hold(app.UIAxes_30, "on")
    grid(app.UIAxes_30, "on")
    title(app.UIAxes_30, 'MilSpec: Short Period')
    xlabel(app.UIAxes_30, 'Damping Ratio \zeta SP')
    ylabel(app.UIAxes_30, 'Nat. Frequency \omega_n SP')
    
    y_range = [min(min(Wn_SP), 0) max(max(Wn_SP), max(wd_z(3).region(:, 2)))];
    ylim(app.UIAxes_30, y_range)
    
    fill(app.UIAxes_30, wd_z(3).region(:,1), wd_z(3).region(:,2), "k", "FaceColor", "r"      , "FaceAlpha", 0.7, "EdgeAlpha", 0.0)
    fill(app.UIAxes_30, wd_z(2).region(:,1), wd_z(2).region(:,2), "k", "FaceColor", '#EDB120', "FaceAlpha", 0.7, "EdgeAlpha", 0.0)
    fill(app.UIAxes_30, wd_z(1).region(:,1), wd_z(1).region(:,2), "k", "FaceColor", "g"      , "FaceAlpha", 0.7, "EdgeAlpha", 0.0)
    
    semilogx(app.UIAxes_30, zeta_SP(2), Wn_SP(2), 'xm',"MarkerSize", 6,'LineWidth', 1.5)
    semilogx(app.UIAxes_30, wd_z(1).region(:, 1), wd_z(1).region(:, 2), "Color", "k", "LineWidth", 1.5)
    semilogx(app.UIAxes_30, wd_z(2).region(:, 1), wd_z(2).region(:, 2), "Color", "k", "LineWidth", 1.5)
    semilogx(app.UIAxes_30, wd_z(3).region(:, 1), wd_z(3).region(:, 2), "Color", "k", "LineWidth", 1.5)
    
    hold(app.UIAxes_30, "off")
    
    
    %% SP wn/ dndalpha      
    loglog(app.UIAxes_31, dn_dalpha, Wn_SP(1), 'or',"MarkerSize", 6,'LineWidth', 1.5)
    hold(app.UIAxes_31, "on")
    grid(app.UIAxes_31, "on")
    xlabel(app.UIAxes_31, ' n/\alpha [g/rad]')
    ylabel(app.UIAxes_31, ' Nat. Frequency \omega_n SP')
    title(app.UIAxes_31, 'MilSpec: Short Period')
    
    fill(app.UIAxes_31, [horz_points fliplr(boundary(1, 2).dndalpha) boundary(2, 2).dndalpha fliplr(boundary(2, 3).dndalpha)], [max(app.UIAxes_31.YLim).*ones(size(horz_points)) fliplr(boundary(1, 2).natFreq) boundary(2, 2).natFreq fliplr(boundary(2, 3).natFreq)],...
        "k", "FaceColor", "r"      , "FaceAlpha", 0.7, "EdgeAlpha", 0.0)
    fill(app.UIAxes_31, [boundary(1, 2).dndalpha fliplr(boundary(1, 1).dndalpha) boundary(2, 1).dndalpha fliplr(boundary(2, 2).dndalpha)], [boundary(1, 2).natFreq fliplr(boundary(1, 1).natFreq) boundary(2, 1).natFreq fliplr(boundary(2, 2).natFreq)],...
        "k", "FaceColor", '#EDB120', "FaceAlpha", 0.7, "EdgeAlpha", 0.0)
    fill(app.UIAxes_31, [boundary(1, 1).dndalpha fliplr(boundary(2, 1).dndalpha)], [boundary(1, 1).natFreq fliplr(boundary(2, 1).natFreq)],...
        "k", "FaceColor", "g"      , "FaceAlpha", 0.7, "EdgeAlpha", 0.0)
    
    loglog(app.UIAxes_31, dn_dalpha, Wn_SP(2), 'xm',"MarkerSize", 6,'LineWidth', 1.5)
    
    for i = 1:2
        for j = 1:length(scaler(1,:))
            loglog(app.UIAxes_31, boundary(i, j).dndalpha, boundary(i, j).natFreq, 'k', 'LineWidth', 1.5);
        end
    end
    
    hold(app.UIAxes_31, "off")
    
    
    %% DUTCH ROLL
    for j = 1:3
        DR(j).x = (0:0.01:1);
        DR(j).y = DR_zetaWn(j)./DR(j).x;
        %DR(j).x(DR(j).y>5) = NaN;
        DR(j).y(DR(j).y>5) = 5;
        DR(j).y(isnan(DR(j).y)) = DR(j).y(2);
        DR(j).x(DR(j).x<DR_zeta(j)) = DR_zeta(j);
        DR(j).y(DR(j).y<DR_Wn(j)) = DR_Wn(j);
    end
    
    hold(app.UIAxes_32, "on")
    xlabel(app.UIAxes_32, ' Damping Ratio \zeta DR')
    ylabel(app.UIAxes_32, ' Nat. Frequency \omega_n DR')
    title(app.UIAxes_32, 'MilSpec: Dutch Roll')
    
    
    fill(app.UIAxes_32, [DR(1).x fliplr(DR(1).x)], [5*ones(size(DR(1).y)) fliplr(DR(1).y)],...
         "k", "FaceColor", "g"      , "FaceAlpha", 0.7, "EdgeAlpha", 1.0)
    fill(app.UIAxes_32, [DR(1).x fliplr(DR(2).x)], [DR(1).y fliplr(DR(2).y)],...
         "k", "FaceColor", '#EDB120', "FaceAlpha", 0.7, "EdgeAlpha", 1.0)
    fill(app.UIAxes_32, [DR(3).x fliplr(DR(2).x) 0], [DR(3).y fliplr(DR(2).y) 5],...
         "k", "FaceColor", "r"      , "FaceAlpha", 0.7, "EdgeAlpha", 1.0)
    
    plot(app.UIAxes_32, zeta_DR(1), Wn_DR(1), 'or', "MarkerSize", 6, 'LineWidth', 1.5)
    plot(app.UIAxes_32, zeta_DR(2), Wn_DR(2), 'xm', "MarkerSize", 6, 'LineWidth', 1.5)
    
    grid(app.UIAxes_32, "on")
    hold(app.UIAxes_32, "off")

end


function controls_startup(app)

    aircraftStructure(app);
    
    app.CTRL(1).units       = '';
    app.CTRL(2).units       = '[deg]';
    app.CTRL(3).units       = '[deg]';
    app.CTRL(4).units       = '[deg]';
    app.CTRL(5).units       = '[deg]';
    
    app.CTRL(1).colour      = 'r';
    app.CTRL(2).colour      = 'g';
    app.CTRL(3).colour      = 'c';
    app.CTRL(4).colour      = 'm';
    app.CTRL(5).colour      = 'b';
    
    for k = 1:5
        app.CTRL(k).name    = app.ChangeControlDataButtonGroup.Buttons(k).Text;
        app.CTRL(k).Ulabel  = ['\Delta ' app.CTRL(k).name ' ' app.CTRL(k).units];
        app.CTRL(k).Axes    = app.UIAxes_ControlDesigner;
        
        % everything below is subject to change by the user
        % on Manual Controls tab
        app.CTRL(k).ctrlT   = app.TimeSpanEditField.Value;
        app.CTRL(k).U_i     = 0;
        app.CTRL(k).U_f     = 0;
        app.CTRL(k).DT      = app.TimeStepEditField.Value;
        app.CTRL(k).window  = 0.1;
        
        % other tabs
        app.CTRL(k).Uextrema= [app.AIRCRAFT.CtrlLim.Lwr(k) app.AIRCRAFT.CtrlLim.Upr(k)];
        app.CTRL(k).Textrema= [0 app.CTRL(k).ctrlT];
        
        
        app.CTRL(k).Tlim    = app.CTRL(k).Textrema;
        app.CTRL(k).Ulim    = app.CTRL(k).Uextrema;
        app.CTRL(k).points  = [app.CTRL(k).Textrema(1) app.CTRL(k).U_i;
                               app.CTRL(k).Textrema(2) app.CTRL(k).U_f];
        
        app.SelectPointDropDown.Items           = string(app.CTRL(k).points(:,1));
        app.SelectPointDropDown.Value           = string(app.CTRL(k).points(1,1));
        
    end
    
    ManualControlsUpdate(app);
end


function ManualControlsUpdate(app)

    aircraftStructure(app);
    
    for k = 1:5
        if strcmp(app.CTRL(k).name, app.ChangeControlDataButtonGroup.SelectedObject.Text)
            app.CTRL_k = k;
            break
        end
    end
    
    app.CTRL(k).Textrema= [0 app.CTRL(k).ctrlT];
    app.CTRL(k).Uextrema= [app.AIRCRAFT.CtrlLim.Lwr(k) app.AIRCRAFT.CtrlLim.Upr(k)];
        
    app.XminSpinner.Limits = [-inf inf];
    app.XmaxSpinner.Limits = [-inf inf];
    app.YminSpinner.Limits = [-inf inf];
    app.YmaxSpinner.Limits = [-inf inf];
    
    app.XminSpinner.Value  =  app.CTRL(k).Tlim(1);
    app.XmaxSpinner.Value  =  min(app.CTRL(k).Tlim(2), app.CTRL(k).ctrlT);
    app.XminSpinner.Limits = [app.CTRL(k).Textrema(1) app.XmaxSpinner.Value];
    app.XmaxSpinner.Limits = [app.XminSpinner.Value app.CTRL(k).Textrema(2)];
    
    app.XSpinner.Step       = app.TimeStepEditField.Value;
    app.XminSpinner.Step    = app.TimeStepEditField.Value;
    app.XmaxSpinner.Step    = app.TimeStepEditField.Value;
                
    app.YminSpinner.Value  =  max(app.CTRL(k).Ulim(1), app.CTRL(k).Uextrema(1));
    app.YmaxSpinner.Value  =  min(app.CTRL(k).Ulim(2), app.CTRL(k).Uextrema(2));
    app.YminSpinner.Limits = [app.CTRL(k).Uextrema(1) app.YmaxSpinner.Value];
    app.YmaxSpinner.Limits = [app.YminSpinner.Value app.CTRL(k).Uextrema(2)];
    
    app.CTRL(k).points(1,:) = [app.CTRL(k).Textrema(1) app.CTRL(k).U_i];
    app.CTRL(k).points(end,:)=[app.CTRL(k).Textrema(2) app.CTRL(k).U_f];
    
    app.InitialControlValueEditField.Value  = app.CTRL(k).U_i;
    app.FinalControlValueEditField.Value    = app.CTRL(k).U_f;
    app.MovingAverageWindowsEditField.Value = app.CTRL(k).window;
    
    app.SelectPointDropDown.Items           = string(app.CTRL(k).points(:,1));
    
    T  = app.CTRL(k).Textrema(1):app.CTRL(k).DT:app.CTRL(k).Textrema(2);
    PT = app.CTRL(k).points(:,1);
    PU = app.CTRL(k).points(:,2);
    U  = zeros(1, length(T));
    for i = 1:length(PT)-1
        U(T>=PT(i) & T<=PT(i+1)) = linspace(PU(i), PU(i+1), length(T(T>=PT(i) & T<=PT(i+1))));
    end
    U_Ave = smooth(U, app.CTRL(k).window*app.CTRL(k).DT);
    
    
    cla(app.UIAxes_ControlDesigner)
    
    hold(app.UIAxes_ControlDesigner  , "on")
    plot(app.UIAxes_ControlDesigner  , T , U_Ave, "Color", app.CTRL(k).colour, "LineStyle", "-"  , "LineWidth", 1.5)
    plot(app.UIAxes_ControlDesigner  , T , U    , "Color", app.CTRL(k).colour, "LineStyle", "--"  , "LineWidth", 1)
    plot(app.UIAxes_ControlDesigner  , PT, PU   , "Color", app.CTRL(k).colour, "LineStyle", "none", "Marker","o")
    ylabel(app.UIAxes_ControlDesigner, app.CTRL(k).Ulabel)
    xlim(app.UIAxes_ControlDesigner  , app.CTRL(k).Tlim)
    ylim(app.UIAxes_ControlDesigner  , app.CTRL(k).Ulim)
    grid(app.UIAxes_ControlDesigner  , "on")
    hold(app.UIAxes_ControlDesigner  , "off")
    
    app.CTRL(k).U_Ave = U_Ave;

end


function updateTransient(app)
    
    cla(app.UIAxes_1)
    cla(app.UIAxes_2)
    cla(app.UIAxes_3)
    cla(app.UIAxes_4)
    kts = 1.944;
    deg = 180/pi;
    ft  = 3.28084;
    
    if app.uCheckBox.Value == 1
        hold(app.UIAxes_1, "on")
        plot(app.UIAxes_1, app.TRANSIENT.T, app.TRANSIENT.X_nl(1, :)*kts, "Color", "r", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "u_b")
        hold(app.UIAxes_1, "off")
    end
    if app.vCheckBox.Value == 1
        hold(app.UIAxes_1, "on")
        plot(app.UIAxes_1, app.TRANSIENT.T, app.TRANSIENT.X_nl(2, :)*kts, "Color", "g", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "v_b")
        hold(app.UIAxes_1, "off")
    end
    if app.wCheckBox.Value == 1
        hold(app.UIAxes_1, "on")
        plot(app.UIAxes_1, app.TRANSIENT.T, app.TRANSIENT.X_nl(3, :)*kts, "Color", "b", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "w_b")
        hold(app.UIAxes_1, "off")
    end
    hold(app.UIAxes_1, "on")
    grid(app.UIAxes_1, "on")
    ylabel(app.UIAxes_1, "Body Airspeeds [knots]")
    legend(app.UIAxes_1)
    hold(app.UIAxes_1, "off")
    
    if app.pCheckBox.Value == 1
        hold(app.UIAxes_2, "on")
        plot(app.UIAxes_2, app.TRANSIENT.T, app.TRANSIENT.X_nl(4, :), "Color", "r", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "p_b")
        hold(app.UIAxes_2, "off")
    end
    if app.qCheckBox.Value == 1
        hold(app.UIAxes_2, "on")
        plot(app.UIAxes_2, app.TRANSIENT.T, app.TRANSIENT.X_nl(5, :), "Color", "g", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "q_b")
        hold(app.UIAxes_2, "off")
    end
    if app.rCheckBox.Value == 1
        hold(app.UIAxes_2, "on")
        plot(app.UIAxes_2, app.TRANSIENT.T, app.TRANSIENT.X_nl(6, :), "Color", "b", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "r_b")
        hold(app.UIAxes_2, "off")
    end
    hold(app.UIAxes_2, "on")
    grid(app.UIAxes_2, "on")
    ylabel(app.UIAxes_2, "Body Turn Rates [rad/sec]")
    legend(app.UIAxes_2)
    hold(app.UIAxes_2, "off")
    
    if app.phiCheckBox.Value == 1
        hold(app.UIAxes_3, "on")
        plot(app.UIAxes_3, app.TRANSIENT.T, app.TRANSIENT.X_nl(7, :)*deg, "Color", "r", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "\phi")
        hold(app.UIAxes_3, "off")
    end
    if app.thetaCheckBox.Value == 1
        hold(app.UIAxes_3, "on")
        plot(app.UIAxes_3, app.TRANSIENT.T, app.TRANSIENT.X_nl(8, :)*deg, "Color", "g", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "\theta")
        hold(app.UIAxes_3, "off")
    end
    if app.psiCheckBox.Value == 1
        hold(app.UIAxes_3, "on")
        plot(app.UIAxes_3, app.TRANSIENT.T, app.TRANSIENT.X_nl(9, :)*deg, "Color", "b", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "\psi")
        hold(app.UIAxes_3, "off")
    end
    hold(app.UIAxes_3, "on")
    grid(app.UIAxes_3, "on")
    ylabel(app.UIAxes_3, "Attitudes [deg.]")
    legend(app.UIAxes_3)
    hold(app.UIAxes_3, "off")
    
    if app.xCheckBox.Value == 1
        hold(app.UIAxes_4, "on")
        plot(app.UIAxes_4, app.TRANSIENT.T, app.TRANSIENT.X_nl(10, :), "Color", "r", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "x_e")
        hold(app.UIAxes_4, "off")
    end
    if app.yCheckBox.Value == 1
        hold(app.UIAxes_4, "on")
        plot(app.UIAxes_4, app.TRANSIENT.T, app.TRANSIENT.X_nl(11, :), "Color", "g", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "y_e")
        hold(app.UIAxes_4, "off")
    end
    if app.zCheckBox.Value == 1
        hold(app.UIAxes_4, "on")
        plot(app.UIAxes_4, app.TRANSIENT.T, app.TRANSIENT.X_nl(12, :), "Color", "b", "LineStyle", "-" , "LineWidth", 1.5, "DisplayName", "z_e")
        hold(app.UIAxes_4, "off")
    end
    hold(app.UIAxes_4, "on")
    grid(app.UIAxes_4, "on")
    ylabel(app.UIAxes_4, "Distances [m]")
    legend(app.UIAxes_4)
    hold(app.UIAxes_4, "off")
    
end
        
function updateLinear(app)
        
    AD = app.LINEAR.Stability;
    
    modes = ["-1-" "-2-" "SP" "-4-" "LP" "-6-" "Roll" "Dutch Roll" "-9-" "Alt con/div" "Spiral Roll" "-12"];
    
    for ii = [3 5 7 8 10 11]
        if strcmp(app.ModeofMotionButtonGroup.SelectedObject.Text, modes(ii))
            mode = modes(ii);
            mode_idx = ii;
        end
    end
    
    fig = app.UIAxes_22;
    cla(fig);
    
    colour2 = ['r'  , 'r'  , 'r'  , 'g'  , 'g'  , 'g'  , 'b'  , 'b'  , 'b'  , 'k'  , 'k'  , 'k'];
    colour1 = ['--r', '--r', '--r', '--g', '--g', '--g', '--b', '--b', '--b', '--k', '--k', '--k'];
    colour3 = ['or' , 'or' , 'or' , 'og' , 'og' , 'og' , 'ob' , 'ob' , 'ob' , 'ok' , 'ok' , 'ok'];
    
    extrema = zeros(12, 1);
            
    for coln = mode_idx

        hold(fig, "on")
        grid(fig, "on")

        xlabel(fig, "Time")
        ylabel(fig, 'Real Axis')
        zlabel(fig, "Imaginary Axis")

        for row = 1:12

            plot3(fig, AD(row,coln).lambda(:,3)   , AD(row,coln).lambda(:,1)   , AD(row,coln).lambda(:,2)   , colour1(3*row-2:3*row), "LineWidth", 1.5);
            plot3(fig, AD(row,coln).result(:,3)   , AD(row,coln).result(:,1)   , AD(row,coln).result(:,2)   , colour2(row));
            plot3(fig, AD(row,coln).lambdaEnd(:,3), AD(row,coln).lambdaEnd(:,1), AD(row,coln).lambdaEnd(:,2), colour3(2*row-1:2*row), "LineWidth", 1.5);

            extrema(row) = max(abs(AD(row,coln).lambdaEnd));
            
            x(row, 1) = sqrt((AD(row,coln).lambda(2,1))^2 + (AD(row,coln).lambda(2,2))^2);
            angle(row, 1) = atan2(AD(row,coln).lambda(2,2), AD(row,coln).lambda(2,1));
        end

        set(fig, 'YDir','reverse')
        view(fig, -90, 0)
        ylim(fig, ceil(max(abs(extrema)))*[-1 1])
        zlim(fig, ceil(max(abs(extrema)))*[-1 1])
        hold(fig, "off")
        
        app.UITable_AD.Data = [x angle*(180/pi)];
    end
    
end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        % uncomment before packaging:
        %{
            files = matlab.apputil.getInstalledAppInfo;
            [path,~,~] = fileparts(files(1).location);
            
            cd([path '/NUUAVFlightSimulator']);
        %}
        app.DIR.source = pwd;
        
        
        %% Add Paths
        addpath('FlightSim');
        addpath('FlightSim/CoordinateTransformation');
        addpath('FlightSim/Dynamics');
        addpath('FlightSim/Forces');
        addpath('FlightSim/LinearAnalysis');
        
        addpath('savedFiles');
        addpath('savedFiles/savedAircraft');
        addpath('savedFiles/savedAircraft_ProfilePhotos');
        addpath('savedFiles/savedParameters');
        addpath('savedFiles/savedModels');
        addpath('savedFiles/savedKML');
        addpath('savedFiles/savedControls');
        
        %% Update Labels
        
        app.m2Label.Interpreter                     = 'latex';
        app.kgm2Label.Interpreter                   = 'latex';
        app.kgm2Label_2.Interpreter                 = 'latex';
        app.kgm2Label_3.Interpreter                 = 'latex';
        app.kgm2Label_4.Interpreter                 = 'latex';
        app.kgm2Label_5.Interpreter                 = 'latex';
        app.kgm2Label_6.Interpreter                 = 'latex';
        
        app.C_D_0Label.Interpreter                  = 'latex';
        app.kEditFieldLabel.Interpreter             = 'latex';
        
        app.C_L_0EditFieldLabel.Interpreter         = 'latex';
        app.C_L_alphaLabel.Interpreter              = 'latex';
        app.C_L_qEditFieldLabel.Interpreter         = 'latex';
        app.C_L_dotalphaEditFieldLabel.Interpreter  = 'latex';
        app.C_L_deltaeEditFieldLabel.Interpreter    = 'latex';
        app.C_L_deltafEditFieldLabel.Interpreter    = 'latex';
        
        app.C_m_0EditFieldLabel.Interpreter         = 'latex';
        app.C_m_alphaEditFieldLabel.Interpreter     = 'latex';
        app.C_m_qEditFieldLabel.Interpreter         = 'latex';
        app.C_m_dotalphaEditFieldLabel.Interpreter  = 'latex';
        app.C_m_deltaeEditFieldLabel.Interpreter    = 'latex';
        app.C_m_deltafEditField_2Label.Interpreter    = 'latex';
        
        app.C_Y_0EditFieldLabel.Interpreter         = 'latex';
        app.C_Y_betaEditFieldLabel.Interpreter      = 'latex';
        app.C_Y_pEditFieldLabel.Interpreter         = 'latex';
        app.C_Y_rEditFieldLabel.Interpreter         = 'latex';
        app.C_Y_dotbetaEditFieldLabel.Interpreter   = 'latex';
        app.C_Y_deltaaEditFieldLabel.Interpreter    = 'latex';
        app.C_Y_deltarEditFieldLabel.Interpreter    = 'latex';
        
        app.C_l_0EditFieldLabel.Interpreter         = 'latex';
        app.C_l_betaEditFieldLabel.Interpreter      = 'latex';
        app.C_l_pEditFieldLabel.Interpreter         = 'latex';
        app.C_l_rEditFieldLabel.Interpreter         = 'latex';
        app.C_l_dotbetaEditFieldLabel.Interpreter   = 'latex';
        app.C_l_deltaaEditFieldLabel.Interpreter    = 'latex';
        app.C_l_deltarEditFieldLabel.Interpreter    = 'latex';
        
        app.C_n_0EditFieldLabel.Interpreter         = 'latex';
        app.C_n_betaEditFieldLabel.Interpreter      = 'latex';
        app.C_n_pEditFieldLabel.Interpreter         = 'latex';
        app.C_n_rEditFieldLabel.Interpreter         = 'latex';
        app.C_n_dotbetaEditFieldLabel.Interpreter   = 'latex';
        app.C_n_deltaaEditFieldLabel.Interpreter    = 'latex';
        app.C_n_deltarEditFieldLabel.Interpreter    = 'latex';
        
        app.kgm3Label.Interpreter                   = 'Latex';
        
        controls_startup(app);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
        
        cd(app.DIR.source)
        
        app.Label.Text = "Simulating...";
        % Need to pause the sim to give the label time to refresh and
        % show the above string.
        pause(0.01);
        
        % update controls
        for k = 1:5
            app.ChangeControlDataButtonGroup.Buttons(k).Value = 1;
            ManualControlsUpdate(app);
        end
        
        parameters = parametersStructure(app);
        
        %% SIMULATION RUNS %%
                        
        output = mainFunction(parameters);
        
        Results_TransientResponse(app, output.Transient);
        
        Results_LinearAnalysis(app, output.LinearAnalysis);
        
        Results_MilitarySpecifications(app, output.MilSpec)
        
        app.Label.Text = "Complete!";
        
        
        end

        % Button pushed function: saveaircraftButton
        function saveaircraftButtonPushed(app, event)

        aircraftStructure(app);
        aircraft = app.AIRCRAFT;
        
        cd(app.DIR.source)
        filename = ['savedFiles/savedAircraft/' app.AircraftNameEditField.Value];
        save(filename, 'aircraft');
        
        end

        % Button pushed function: loadaircraftButton
        function loadaircraftButtonPushed(app, event)
        
        % Open Folder & choose file
        cd(app.DIR.source)
        filename = uigetfile('savedFiles/savedAircraft');
        
        load(filename, 'aircraft');
        
        app.AIRCRAFT = aircraft;
        aircraftEditFields(app);
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        
        % Open Folder & choose file
        cd(app.DIR.source)
        filename = uigetfile('savedFiles/savedAircraft_ProfilePhotos');
        app.Image.ImageSource = filename;
        
        end

        % Button pushed function: ProfileImageFolderButton
        function ProfileImageFolderButtonPushed(app, event)
        
        % Open Folder
        cd(app.DIR.source)
        winopen('savedFiles/savedAircraft_ProfilePhotos');
        
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
     
        parameters = parametersStructure(app);
        
        % Unpack
        aircraft    = app.AIRCRAFT;
        operation   = parameters.operation;
        environment = parameters.environment;
        simulation  = parameters.simulation;
        
        cd(app.DIR.source)
        filename = ['savedFiles/savedParameters/' app.FileNameEditField.Value];
        save(filename, 'aircraft', 'operation', 'environment', 'simulation');
        
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
        
        % Open Folder & choose file
        cd(app.DIR.source)
        filename = uigetfile('savedFiles/savedParameters');
        load(filename);
        
        parameters.aircraft     = aircraft;
        parameters.operation    = operation;
        parameters.environment  = environment;
        parameters.simulation   = simulation;
        
        parametersEditFields(app, parameters);
        
        end

        % Button pushed function: SaveControlsButton
        function SaveControlsButtonPushed(app, event)
            
        savedControls = app.CTRL;
        
        cd(app.DIR.source)
        filename = ['savedFiles/savedControls/' app.ControlFileNameEditField.Value];
        save(filename, 'savedControls');
        
        end

        % Drop down opening function: LoadControlsDropDown
        function LoadControlsDropDownOpening(app, event)
        % Log all the file names in folder into 'allNames' variable.
        cd(app.DIR.source)
        allFiles = dir( 'savedFiles/savedControls/' );
        allNames = { allFiles.name };
        % The drop down menu will show the names of al the files. The
        % first 2 files show as wierd dots so disregard them.
        app.LoadControlsDropDown.Items = allNames(3:end);
        end

        % Value changed function: LoadControlsDropDown
        function LoadControlsDropDownValueChanged(app, event)
        value = app.LoadControlsDropDown.Value;
        app.ControlFileNameEditField.Value = value;
        % if there is no file in the load box (ie the user just pressed
        % 'load' without picking a file first).
        if size(value)==0
            % Do nothing
        else
            cd(app.DIR.source)
            filename = ['savedFiles/savedControls/' value];
            load(filename);
            
            app.CTRL = savedControls;
            
            % update controls
            for k = 1:5
                app.ChangeControlDataButtonGroup.Buttons(k).Value = 1;
                ManualControlsUpdate(app);
            end
            
            app.TimeSpanEditField.Value = app.CTRL(k).ctrlT;
        end
        end

        % Button pushed function: ControlsFolderButton
        function ControlsFolderButtonPushed(app, event)
        % Open Folder 
        cd(app.DIR.source)
        winopen('savedFiles/savedControls');
        end

        % Button pushed function: ImportkmlPathButton
        function ImportkmlPathButtonPushed(app, event)
        [filename, pathname] = uigetfile('*.*', 'savedFiles/');
        app.TRAJECTORY.kmlImport = kml2struct([pathname filename]);
        
        if app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Value == 0
            app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Value = 1;
            PlotTrajectoryRequiresthemappingtoolboxCheckBoxValueChanged(app, event);
        end
        
        for i = 1:length(app.TRAJECTORY.kmlImport)
            hold(app.TRAJECTORY.globefig, "on")
            if strcmp(app.TRAJECTORY.kmlImport(i).Geometry, "Point")
                geoplot3(app.TRAJECTORY.globefig, app.TRAJECTORY.kmlImport(i).Lat, app.TRAJECTORY.kmlImport(i).Lon, app.TRAJECTORY.kmlImport(i).Alt,...
                         "Color", "c", "Marker", "o", "MarkerSize", 6, "LineWidth", 1.5);
            elseif strcmp(app.TRAJECTORY.kmlImport(i).Geometry, "Line")
                geoplot3(app.TRAJECTORY.globefig, app.TRAJECTORY.kmlImport(i).Lat, app.TRAJECTORY.kmlImport(i).Lon, app.TRAJECTORY.kmlImport(i).Alt,...
                         "Color", "m", "LineStyle", "-", "LineWidth", 1.5);
            elseif strcmp(app.TRAJECTORY.kmlImport(i).Geometry, "Polygon")
                geoplot3(app.TRAJECTORY.globefig, app.TRAJECTORY.kmlImport(i).Lat, app.TRAJECTORY.kmlImport(i).Lon, app.TRAJECTORY.kmlImport(i).Alt,...
                         "Color", "y", "Marker", "o", "MarkerSize", 6, "LineWidth", 1.5);
            end
            hold(app.TRAJECTORY.globefig, "off")
        end
        
        end

        % Value changed function: 
        % PlotTrajectoryRequiresthemappingtoolboxCheckBox
        function PlotTrajectoryRequiresthemappingtoolboxCheckBoxValueChanged(app, event)
            value = app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Value;
            
            ft = 3.28084;
            
            if value == 1 
                fig                         = uifigure("Name", "Trajectory");
                app.TRAJECTORY.globefig     = geoglobe(fig);
                
                % plot initial (trimmed) longitude, latitude and altitude
                geoplot3(app.TRAJECTORY.globefig, app.InitialLatitudedegEditField.Value, app.InitialLongitudedegEditField.Value, app.TrimmedAltitudemEditField.Value/ft, ...
                         "Color", 'r', "Marker", "o", "MarkerSize", 9, "LineWidth", 1.5);                
            end
        end

        % Button pushed function: SaveKMLfileButton
        function SaveKMLfileButtonPushed(app, event)
        
        % Open Folder & choose folder
        cd(app.DIR.source)
        foldername = uigetdir('savedFiles/savedKML');
        filename = app.KMLfilenameEditField.Value;
        
        load("savedFiles/KML_temp");
        lat = kml_array(1,:);
        long = kml_array(2,:);
        alt = kml_array(3,:);
        kmlwriteline([foldername '/' filename], lat, long, alt)
        end

        % Drop down opening function: ModelDropDown
        function ModelDropDownOpening(app, event)
                    
        cd(app.DIR.source)
        allModels   = dir('savedFiles/savedModels');
        allNames    = { allModels.name };
        app.ModelDropDown.Items = allNames(3:end);
        
        end

        % Selection changed function: ChangeControlDataButtonGroup
        function ChangeControlDataButtonGroupSelectionChanged(app, event)
            ManualControlsUpdate(app);
        end

        % Value changed function: FinalControlValueEditField, 
        % InitialControlValueEditField, 
        % MovingAverageWindowsEditField, XmaxSpinner, XminSpinner, 
        % YmaxSpinner, YminSpinner
        function UpdateControlsTab_ValueChanged(app, event)
            app.CTRL(app.CTRL_k).Tlim(1) = app.XminSpinner.Value;
            app.CTRL(app.CTRL_k).Tlim(2) = app.XmaxSpinner.Value;
            app.CTRL(app.CTRL_k).Ulim(1) = app.YminSpinner.Value;
            app.CTRL(app.CTRL_k).Ulim(2) = app.YmaxSpinner.Value;
            
            app.CTRL(app.CTRL_k).U_i = app.InitialControlValueEditField.Value;
            app.CTRL(app.CTRL_k).U_f = app.FinalControlValueEditField.Value;
            
            app.CTRL(app.CTRL_k).window = app.MovingAverageWindowsEditField.Value;
            
            ManualControlsUpdate(app);
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            X = round(app.XSpinner.Value/app.CTRL(app.CTRL_k).DT)*app.CTRL(app.CTRL_k).DT;
            Y = app.YSpinner.Value;
            
            for k = 1:length(app.CTRL(app.CTRL_k).points(:,1))-1
                
                if X > app.CTRL(app.CTRL_k).points(k,1) && X < app.CTRL(app.CTRL_k).points(k+1,1)
                    
                    for i = length(app.CTRL(app.CTRL_k).points(:,1)):-1:k+1
                        app.CTRL(app.CTRL_k).points(i+1,:) = app.CTRL(app.CTRL_k).points(i,:);
                    end
                    
                    app.CTRL(app.CTRL_k).points(i,:) = [X Y];
                    break
                    
                end
                
            end
            
            ManualControlsUpdate(app);
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            X = str2double(app.SelectPointDropDown.Value);
            
            
            for k = 2:length(app.CTRL(app.CTRL_k).points(:,1))-1
                
                if X == app.CTRL(app.CTRL_k).points(k,1)
                    
                    for i = k:length(app.CTRL(app.CTRL_k).points(:,1))-1
                        app.CTRL(app.CTRL_k).points(i,:) = app.CTRL(app.CTRL_k).points(i+1,:);
                    end
                    
                    app.CTRL(app.CTRL_k).points(i+1,:) = [];
                    break
                    
                end
                
            end
            
            ManualControlsUpdate(app);
        end

        % Value changed function: TimeSpanEditField, 
        % TimeStepEditField
        function TimeSpanStep_EditFieldValueChanged(app, event)
            for k = 1:5
                app.CTRL(k).ctrlT       = app.TimeSpanEditField.Value;
                app.CTRL(k).Tlim(2)     = app.TimeSpanEditField.Value;
                
                app.CTRL(k).DT          = app.TimeStepEditField.Value;

            end
            ManualControlsUpdate(app);
        end

        % Value changed function: pCheckBox, phiCheckBox, 
        % psiCheckBox, qCheckBox, rCheckBox, thetaCheckBox, 
        % uCheckBox, vCheckBox, wCheckBox, xCheckBox, yCheckBox, 
        % zCheckBox
        function Transient_CheckBoxValueChanged(app, event)
            updateTransient(app)
        end

        % Selection changed function: ModeofMotionButtonGroup
        function ModeofMotionButtonGroupSelectionChanged(app, event)
            updateLinear(app)
        end

        % Selection changed function: PropulsiveDeviceButtonGroup
        function PropulsiveDeviceButtonGroupSelectionChanged(app, event)
            selectedButton = app.PropulsiveDeviceButtonGroup.SelectedObject;
            
            % clear panels
            app.ElecticPanel.Visible = 0;
            app.ConstantSpeedPropellerPanel.Visible = 0;
            app.TurbopropPanel.Visible = 0;
            
            name = selectedButton.Text;
            
            if strcmp(name, 'Electric')
                app.ElecticPanel.Visible = 1;
            elseif strcmp(name, 'Constant Speed Propeller')
                app.ConstantSpeedPropellerPanel.Visible = 1;
            elseif strcmp(name, 'Turbo-Prop')
                app.TurbopropPanel.Visible = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AircraftTab = uitab(app.TabGroup);
            app.AircraftTab.Title = 'Aircraft';
            app.TabGroup2 = uitabgroup(app.AircraftTab);
            app.InertiaGeometryTab = uitab(app.TabGroup2);
            app.InertiaGeometryTab.Title = ' Inertia & Geometry';
            app.MassEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.MassEditField.Value = 2087;
            app.IxxEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.IxxEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.IxxEditField.Value = 5066;
            app.IyyEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.IyyEditField.Value = 6578;
            app.IzzEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.IzzEditField.Value = 10975;
            app.IxzEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.IxzEditField.Value = 203;
            app.IxyEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.IyzEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.MomentofInertiaMatrixLabel = uilabel(app.InertiaGeometryTab);
            app.MomentofInertiaMatrixLabel.Text = 'Moment of Inertia Matrix';
            app.kgLabel = uilabel(app.InertiaGeometryTab);
            app.kgLabel.Text = 'kg';
            app.m2Label = uilabel(app.InertiaGeometryTab);
            app.m2Label.Text = '$m^{2}$';
            app.mLabel = uilabel(app.InertiaGeometryTab);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.InertiaGeometryTab);
            app.mLabel_2.Text = 'm';
            app.PlanformAreaEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.PlanformAreaEditFieldLabel.Text = 'Planform Area';
            app.PlanformAreaEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.PlanformAreaEditField.Value = 16.29;
            app.WingSpanEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.WingSpanEditFieldLabel.Text = 'Wing Span';
            app.WingSpanEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.WingSpanEditField.Value = 10.12;
            app.MeanAerodynamicChordLengthEditFieldLabel = uilabel(app.InertiaGeometryTab);
            app.MeanAerodynamicChordLengthEditFieldLabel.Text = 'Mean Aerodynamic Chord Length';
            app.MeanAerodynamicChordLengthEditField = uieditfield(app.InertiaGeometryTab, 'numeric');
            app.MeanAerodynamicChordLengthEditField.Value = 1.652;
            app.kgm2Label = uilabel(app.InertiaGeometryTab);
            app.kgm2Label.Text = '$kg m^2$';
            app.kgm2Label_2 = uilabel(app.InertiaGeometryTab);
            app.kgm2Label_2.Text = '$kg m^2$';
            app.kgm2Label_3 = uilabel(app.InertiaGeometryTab);
            app.kgm2Label_3.Text = '$kg m^2$';
            app.kgm2Label_4 = uilabel(app.InertiaGeometryTab);
            app.kgm2Label_4.Text = '$kg m^2$';
            app.kgm2Label_5 = uilabel(app.InertiaGeometryTab);
            app.kgm2Label_5.Text = '$kg m^2$';
            app.kgm2Label_6 = uilabel(app.InertiaGeometryTab);
            app.kgm2Label_6.Text = '$kg m^2$';
            app.PropulsionControlSurfacesTab = uitab(app.TabGroup2);
            app.PropulsionControlSurfacesTab.Title = 'Propulsion & Control Surfaces';
            app.ElevatorUpperLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.ElevatorUpperLimitEditFieldLabel.Text = 'Elevator Upper Limit';
            app.ElevatorUpperLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.ElevatorUpperLimitEditField.Value = 25;
            app.ElevatorLowerLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.ElevatorLowerLimitEditFieldLabel.Text = 'Elevator Lower Limit';
            app.ElevatorLowerLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.ElevatorLowerLimitEditField.Value = -25;
            app.AileronUpperLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.AileronUpperLimitEditFieldLabel.Text = 'Aileron Upper Limit';
            app.AileronUpperLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.AileronUpperLimitEditField.Value = 25;
            app.AileronLowerLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.AileronLowerLimitEditFieldLabel.Text = 'Aileron Lower Limit';
            app.AileronLowerLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.AileronLowerLimitEditField.Value = -25;
            app.RudderLowerLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.RudderLowerLimitEditFieldLabel.Text = 'Rudder Lower Limit';
            app.RudderLowerLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.RudderLowerLimitEditField.Value = -25;
            app.RudderUpperLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.RudderUpperLimitEditFieldLabel.Text = 'Rudder Upper Limit';
            app.RudderUpperLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.RudderUpperLimitEditField.Value = 25;
            app.ControlSurfacesLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.ControlSurfacesLabel.Text = 'Control Surfaces ';
            app.FlapsLowerLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.FlapsLowerLimitEditFieldLabel.Text = 'Flaps Lower Limit';
            app.FlapsLowerLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.FlapsUpperLimitEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.FlapsUpperLimitEditFieldLabel.Text = 'Flaps Upper Limit';
            app.FlapsUpperLimitEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.FlapsUpperLimitEditField.Value = 25;
            app.degLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel.Text = 'deg.';
            app.degLabel_2 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_2.Text = 'deg.';
            app.degLabel_3 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_3.Text = 'deg.';
            app.degLabel_4 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_4.Text = 'deg.';
            app.degLabel_5 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_5.Text = 'deg.';
            app.degLabel_6 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_6.Text = 'deg.';
            app.degLabel_7 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_7.Text = 'deg.';
            app.degLabel_8 = uilabel(app.PropulsionControlSurfacesTab);
            app.degLabel_8.Text = 'deg.';
            app.ElecticPanel = uipanel(app.PropulsionControlSurfacesTab);
            app.ElecticPanel.Title = 'Electic';
            app.BatteryVoltageEditFieldLabel = uilabel(app.ElecticPanel);
            app.BatteryVoltageEditFieldLabel.Text = 'Battery Voltage';
            app.BatteryVoltageEditField = uieditfield(app.ElecticPanel, 'numeric');
            app.BatteryVoltageEditField.Value = 40;
            app.BatteryCapacityEditFieldLabel = uilabel(app.ElecticPanel);
            app.BatteryCapacityEditFieldLabel.Text = 'Battery Capacity';
            app.BatteryCapacityEditField = uieditfield(app.ElecticPanel, 'numeric');
            app.BatteryCapacityEditField.Value = 10000;
            app.mAhLabel = uilabel(app.ElecticPanel);
            app.mAhLabel.Text = 'mAh';
            app.VLabel = uilabel(app.ElecticPanel);
            app.VLabel.Text = 'V';
            app.motorKVEditFieldLabel = uilabel(app.ElecticPanel);
            app.motorKVEditFieldLabel.Text = 'motor KV';
            app.motorKVEditField = uieditfield(app.ElecticPanel, 'numeric');
            app.motorKVEditField.Value = 60;
            app.rpmVLabel = uilabel(app.ElecticPanel);
            app.rpmVLabel.Text = 'rpm/V';
            app.PropulsiveDeviceButtonGroup = uibuttongroup(app.PropulsionControlSurfacesTab);
            app.PropulsiveDeviceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PropulsiveDeviceButtonGroupSelectionChanged, true);
            app.PropulsiveDeviceButtonGroup.Title = 'Propulsive Device';
            app.ElectricButton = uiradiobutton(app.PropulsiveDeviceButtonGroup);
            app.ElectricButton.Text = 'Electric';
            app.ConstantSpeedPropellerButton = uiradiobutton(app.PropulsiveDeviceButtonGroup);
            app.ConstantSpeedPropellerButton.Text = 'Constant Speed Propeller';
            app.TurboPropButton = uiradiobutton(app.PropulsiveDeviceButtonGroup);
            app.TurboPropButton.Text = 'Turbo-Prop';
            app.TurboPropButton.Value = true;
            app.ConstantSpeedPropellerPanel = uipanel(app.PropulsionControlSurfacesTab);
            app.ConstantSpeedPropellerPanel.Title = 'Constant Speed Propeller';
            app.WLabel_CSP = uilabel(app.ConstantSpeedPropellerPanel);
            app.WLabel_CSP.Text = 'W';
            app.MaximumPowerEditFieldLabel = uilabel(app.ConstantSpeedPropellerPanel);
            app.MaximumPowerEditFieldLabel.Text = 'Maximum Power';
            app.MaximumPowerEditField_CSP = uieditfield(app.ConstantSpeedPropellerPanel, 'numeric');
            app.MaximumPowerEditField_CSP.Value = 708000;
            app.EfficiencyEditFieldLabel = uilabel(app.ConstantSpeedPropellerPanel);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField_CSP = uieditfield(app.ConstantSpeedPropellerPanel, 'numeric');
            app.EfficiencyEditField_CSP.Value = 0.8;
            app.TurbochargeAltitudeEditFieldLabel = uilabel(app.ConstantSpeedPropellerPanel);
            app.TurbochargeAltitudeEditFieldLabel.Text = 'Turbo-charge Altitude';
            app.TurbochargeAltitudeEditField_CSP = uieditfield(app.ConstantSpeedPropellerPanel, 'numeric');
            app.ftLabel_2 = uilabel(app.ConstantSpeedPropellerPanel);
            app.ftLabel_2.Text = 'ft.';
            app.TurbopropPanel = uipanel(app.PropulsionControlSurfacesTab);
            app.TurbopropPanel.Title = 'Turbo-prop';
            app.MaximumPowerLabel = uilabel(app.TurbopropPanel);
            app.MaximumPowerLabel.Text = 'Maximum Power';
            app.MaximumPowerEditField_TP = uieditfield(app.TurbopropPanel, 'numeric');
            app.MaximumPowerEditField_TP.Value = 708000;
            app.WLabel_TP = uilabel(app.TurbopropPanel);
            app.WLabel_TP.Text = 'W';
            app.EfficiencyEditField_2Label = uilabel(app.TurbopropPanel);
            app.EfficiencyEditField_2Label.Text = 'Efficiency';
            app.EfficiencyEditField_TP = uieditfield(app.TurbopropPanel, 'numeric');
            app.EfficiencyEditField_TP.Value = 0.8;
            app.AltitudeIndexEditFieldLabel = uilabel(app.TurbopropPanel);
            app.AltitudeIndexEditFieldLabel.Text = 'Altitude Index';
            app.AltitudeIndexEditField_TP = uieditfield(app.TurbopropPanel, 'numeric');
            app.AltitudeIndexEditField_TP.Value = 0.7;
            app.PropellerLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.PropellerLabel.Text = 'Propeller';
            app.CT_J2EditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CT_J2EditFieldLabel.Text = 'CT_J2';
            app.CT_J2EditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CT_J2EditField.Value = -0.2372;
            app.CT_JEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CT_JEditFieldLabel.Text = 'CT_J';
            app.CT_JEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CT_JEditField.Value = 0.0444;
            app.CT_0EditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CT_0EditFieldLabel.Text = 'CT_0';
            app.CT_0EditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CT_0EditField.Value = 0.7592;
            app.CP_J3EditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CP_J3EditFieldLabel.Text = 'CP_J3';
            app.CP_J3EditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CP_J3EditField.Value = 0.1584;
            app.CP_J2EditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CP_J2EditFieldLabel.Text = 'CP_J2';
            app.CP_J2EditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CP_J2EditField.Value = -0.9308;
            app.CP_JEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CP_JEditFieldLabel.Text = 'CP_J';
            app.CP_JEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CP_JEditField.Value = 0.5843;
            app.CP_0EditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.CP_0EditFieldLabel.Text = 'CP_0';
            app.CP_0EditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.CP_0EditField.Value = 1.2054;
            app.DiameterEditFieldLabel = uilabel(app.PropulsionControlSurfacesTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.PropulsionControlSurfacesTab, 'numeric');
            app.DiameterEditField.Value = 1;
            app.mLabel_3 = uilabel(app.PropulsionControlSurfacesTab);
            app.mLabel_3.Text = 'm';
            app.AerodynamicsTab = uitab(app.TabGroup2);
            app.AerodynamicsTab.Title = 'Aerodynamics';
            app.LiftCoefficientsLabel = uilabel(app.AerodynamicsTab);
            app.LiftCoefficientsLabel.Text = 'Lift Coefficients';
            app.DragCoefficientsLabel = uilabel(app.AerodynamicsTab);
            app.DragCoefficientsLabel.Text = 'Drag Coefficients';
            app.PitchMomentCoefficientsLabel = uilabel(app.AerodynamicsTab);
            app.PitchMomentCoefficientsLabel.Text = 'Pitch Moment Coefficients';
            app.SideForceCoefficientsLabel = uilabel(app.AerodynamicsTab);
            app.SideForceCoefficientsLabel.Text = 'Side Force Coefficients';
            app.RollMomentCoefficientsLabel = uilabel(app.AerodynamicsTab);
            app.RollMomentCoefficientsLabel.Text = 'Roll Moment Coefficients';
            app.YawMomentCoefficientsLabel = uilabel(app.AerodynamicsTab);
            app.YawMomentCoefficientsLabel.Text = 'Yaw Moment Coefficients';
            app.perunitMACLabel = uilabel(app.AerodynamicsTab);
            app.perunitMACLabel.Text = 'per unit MAC';
            app.C_L_0EditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_L_0EditFieldLabel.Text = '$C_{L_{0}}$';
            app.C_L_0EditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_L_0EditField.Value = 0.2439;
            app.C_L_alphaLabel = uilabel(app.AerodynamicsTab);
            app.C_L_alphaLabel.Text = '$C_{L_{\alpha}}$';
            app.C_L_alphaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_L_alphaEditField.Value = 4.659;
            app.C_L_qEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_L_qEditFieldLabel.Text = '$C_{L_ {q}}$';
            app.C_L_qEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_L_qEditField.Value = 7.96;
            app.C_D_0Label = uilabel(app.AerodynamicsTab);
            app.C_D_0Label.Text = '$C_{D_{0}}$';
            app.C_D_0EditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_D_0EditField.Value = 0.0215;
            app.C_m_0EditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_m_0EditFieldLabel.Text = '$C_{m_{0}}$';
            app.C_m_0EditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_m_0EditField.Value = 0.06;
            app.C_m_qEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_m_qEditFieldLabel.Text = '$C_{m_ {q}}$';
            app.C_m_qEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_m_qEditField.Value = -17.72;
            app.C_m_alphaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_m_alphaEditFieldLabel.Text = '$C_{m_{\alpha}}$';
            app.C_m_alphaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_m_alphaEditField.Value = -0.802;
            app.C_Y_0EditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_0EditFieldLabel.Text = '$C_{Y_{0}}$';
            app.C_Y_0EditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_pEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_pEditFieldLabel.Text = '$C_{Y_ {p}}$';
            app.C_Y_pEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_pEditField.Value = -0.128;
            app.C_l_0EditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_0EditFieldLabel.Text = '$C_{l_{0}}$';
            app.C_l_0EditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_betaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_betaEditFieldLabel.Text = '$C_{l_{\beta}}$';
            app.C_l_betaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_betaEditField.Value = -0.0852;
            app.C_l_dotbetaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_dotbetaEditFieldLabel.Text = '$C_{l_{\dot{\beta}}}$';
            app.C_l_dotbetaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_dotbetaEditField.Value = -0.0004;
            app.C_n_0EditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_0EditFieldLabel.Text = '$C_{n_{0}}$';
            app.C_n_0EditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_dotbetaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_dotbetaEditFieldLabel.Text = '$C_{n_{\dot{\beta}}}$';
            app.C_n_dotbetaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_dotbetaEditField.Value = 0.0015;
            app.C_n_betaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_betaEditFieldLabel.Text = '$C_{n_{\beta}}$';
            app.C_n_betaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_betaEditField.Value = 0.107;
            app.C_L_dotalphaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_L_dotalphaEditFieldLabel.Text = '$C_{L_{\dot{\alpha}}}$';
            app.C_L_dotalphaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_L_dotalphaEditField.Value = -1.987;
            app.C_L_deltaeEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_L_deltaeEditFieldLabel.Text = '$C_{L_{\delta e}}$';
            app.C_L_deltaeEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_L_deltaeEditField.Value = 0.532;
            app.C_m_dotalphaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_m_dotalphaEditFieldLabel.Text = '$C_{m_{\dot{\alpha}}}$';
            app.C_m_dotalphaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_m_dotalphaEditField.Value = -2.21;
            app.C_m_deltaeEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_m_deltaeEditFieldLabel.Text = '$C_{m_{\delta e}}$';
            app.C_m_deltaeEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_m_deltaeEditField.Value = -1.822;
            app.C_Y_rEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_rEditFieldLabel.Text = '$C_{Y_{r}}$';
            app.C_Y_rEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_rEditField.Value = 0.336;
            app.C_Y_deltaaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_deltaaEditFieldLabel.Text = '$C_{Y_{\delta a}}$';
            app.C_Y_deltaaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_deltarEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_deltarEditFieldLabel.Text = '$C_{Y_{\delta r}}$';
            app.C_Y_deltarEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_deltarEditField.Value = 0.05;
            app.C_Y_dotbetaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_dotbetaEditFieldLabel.Text = '$C_{Y_{\dot{\beta}}}$';
            app.C_Y_dotbetaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_dotbetaEditField.Value = -0.0032;
            app.C_l_pEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_pEditFieldLabel.Text = '$C_{l_ {p}}$';
            app.C_l_pEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_pEditField.Value = -0.328;
            app.C_l_rEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_rEditFieldLabel.Text = '$C_{l_{r}}$';
            app.C_l_rEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_rEditField.Value = 0.0776;
            app.C_l_deltaaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_deltaaEditFieldLabel.Text = '$C_{l_{\delta a}}$';
            app.C_l_deltaaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_deltaaEditField.Value = -0.164;
            app.C_l_deltarEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_l_deltarEditFieldLabel.Text = '$C_{l_{\delta r}}$';
            app.C_l_deltarEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_l_deltarEditField.Value = 0.0302;
            app.C_n_pEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_pEditFieldLabel.Text = '$C_{n_ {p}}$';
            app.C_n_pEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_pEditField.Value = -0.0226;
            app.C_n_rEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_rEditFieldLabel.Text = '$C_{n_{r}}$';
            app.C_n_rEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_rEditField.Value = -0.16;
            app.C_n_deltaaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_deltaaEditFieldLabel.Text = '$C_{n_{\delta a}}$';
            app.C_n_deltaaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_deltaaEditField.Value = 0.0048;
            app.C_n_deltarEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_n_deltarEditFieldLabel.Text = '$C_{n_{\delta r}}$';
            app.C_n_deltarEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_n_deltarEditField.Value = -0.115;
            app.CentreofGravityDuringMeasurementsEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.CentreofGravityDuringMeasurementsEditFieldLabel.Text = 'Centre of Gravity During Measurements';
            app.CentreofGravityDuringMeasurementsEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.CentreofGravityDuringMeasurementsEditField.Value = 0.265;
            app.kEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.kEditField.Value = 0.0555;
            app.C_Y_betaEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_Y_betaEditFieldLabel.Text = '$C_{Y_{\beta}}$';
            app.C_Y_betaEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_Y_betaEditField.Value = -0.507;
            app.C_L_deltafEditFieldLabel = uilabel(app.AerodynamicsTab);
            app.C_L_deltafEditFieldLabel.Text = '$C_{L_{\delta f}}$';
            app.C_L_deltafEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.C_m_deltafEditField_2Label = uilabel(app.AerodynamicsTab);
            app.C_m_deltafEditField_2Label.Text = '$C_{m_{\delta f}}$';
            app.C_m_deltafEditField = uieditfield(app.AerodynamicsTab, 'numeric');
            app.OptionsTab = uitab(app.TabGroup2);
            app.OptionsTab.Title = 'Options';
            app.loadaircraftButton = uibutton(app.OptionsTab, 'push');
            app.loadaircraftButton.ButtonPushedFcn = createCallbackFcn(app, @loadaircraftButtonPushed, true);
            app.loadaircraftButton.Text = 'load aircraft';
            app.saveaircraftButton = uibutton(app.OptionsTab, 'push');
            app.saveaircraftButton.ButtonPushedFcn = createCallbackFcn(app, @saveaircraftButtonPushed, true);
            app.saveaircraftButton.Text = 'save aircraft';
            app.AircraftNameEditFieldLabel = uilabel(app.OptionsTab);
            app.AircraftNameEditFieldLabel.Text = 'Aircraft Name';
            app.AircraftNameEditField = uieditfield(app.OptionsTab, 'text');
            app.AircraftDescriptionTextAreaLabel = uilabel(app.OptionsTab);
            app.AircraftDescriptionTextAreaLabel.Text = 'Aircraft Description';
            app.AircraftDescriptionTextArea = uitextarea(app.OptionsTab);
            app.Image = uiimage(app.OptionsTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ProfileImageFolderButton = uibutton(app.OptionsTab, 'push');
            app.ProfileImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileImageFolderButtonPushed, true);
            app.ProfileImageFolderButton.Text = 'Profile Image Folder';
            app.AircraftClassDropDownLabel = uilabel(app.OptionsTab);
            app.AircraftClassDropDownLabel.Text = 'Aircraft Class';
            app.AircraftClassDropDown = uidropdown(app.OptionsTab);
            app.AircraftClassDropDown.Items = {'I', 'II', 'III', 'IV'};
            app.AircraftClassDropDown.Value = 'I';
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.gravityEditFieldLabel = uilabel(app.EnvironmentTab);
            app.gravityEditFieldLabel.Text = 'gravity';
            app.gravityEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.gravityEditField.Value = 9.81;
            app.WindSpeedLabel = uilabel(app.EnvironmentTab);
            app.WindSpeedLabel.Text = 'Wind Speed';
            app.WindSpeedEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SeaLevelDensityEditFieldLabel = uilabel(app.EnvironmentTab);
            app.SeaLevelDensityEditFieldLabel.Text = 'Sea Level Density';
            app.SeaLevelDensityEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SeaLevelDensityEditField.Value = 1.225;
            app.SeaLevelTemperatureEditFieldLabel = uilabel(app.EnvironmentTab);
            app.SeaLevelTemperatureEditFieldLabel.Text = 'Sea Level Temperature';
            app.SeaLevelTemperatureEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SeaLevelTemperatureEditField.Value = 288.16;
            app.LapseRateEditFieldLabel = uilabel(app.EnvironmentTab);
            app.LapseRateEditFieldLabel.Text = 'Lapse Rate';
            app.LapseRateEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.LapseRateEditField.Value = 0.0065;
            app.UniversalGasConstantEditFieldLabel = uilabel(app.EnvironmentTab);
            app.UniversalGasConstantEditFieldLabel.Text = 'Universal Gas Constant';
            app.UniversalGasConstantEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.UniversalGasConstantEditField.Value = 287;
            app.WindBearingEditFieldLabel = uilabel(app.EnvironmentTab);
            app.WindBearingEditFieldLabel.Text = 'Wind Bearing';
            app.WindBearingEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SealLevelPressureEditFieldLabel = uilabel(app.EnvironmentTab);
            app.SealLevelPressureEditFieldLabel.Text = 'Seal Level Pressure';
            app.SealLevelPressureEditField = uieditfield(app.EnvironmentTab, 'numeric');
            app.SealLevelPressureEditField.Value = 101325;
            app.ktsLabel = uilabel(app.EnvironmentTab);
            app.ktsLabel.Text = 'kts';
            app.degTRUELabel = uilabel(app.EnvironmentTab);
            app.degTRUELabel.Text = 'deg. TRUE';
            app.kgm3Label = uilabel(app.EnvironmentTab);
            app.kgm3Label.Text = '$kg/m^{3}$';
            app.KLabel = uilabel(app.EnvironmentTab);
            app.KLabel.Text = 'K';
            app.PaLabel = uilabel(app.EnvironmentTab);
            app.PaLabel.Text = 'Pa';
            app.NkgLabel = uilabel(app.EnvironmentTab);
            app.NkgLabel.Text = 'N/kg';
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = 'Operation';
            app.TabGroup4 = uitabgroup(app.OperationTab);
            app.ManualControlsTab = uitab(app.TabGroup4);
            app.ManualControlsTab.Title = 'Manual Controls';
            app.ChangeControlDataButtonGroup = uibuttongroup(app.ManualControlsTab);
            app.ChangeControlDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangeControlDataButtonGroupSelectionChanged, true);
            app.ChangeControlDataButtonGroup.Title = 'Change Control Data';
            app.ThrustButton = uiradiobutton(app.ChangeControlDataButtonGroup);
            app.ThrustButton.Text = 'Thrust';
            app.ThrustButton.Value = true;
            app.ElevatorButton = uiradiobutton(app.ChangeControlDataButtonGroup);
            app.ElevatorButton.Text = 'Elevator';
            app.AileronButton = uiradiobutton(app.ChangeControlDataButtonGroup);
            app.AileronButton.Text = 'Aileron';
            app.RudderButton = uiradiobutton(app.ChangeControlDataButtonGroup);
            app.RudderButton.Text = 'Rudder';
            app.FlapsButton = uiradiobutton(app.ChangeControlDataButtonGroup);
            app.FlapsButton.Text = 'Flaps';
            app.YminSpinnerLabel = uilabel(app.ManualControlsTab);
            app.YminSpinnerLabel.Text = 'Ymin';
            app.YminSpinner = uispinner(app.ManualControlsTab);
            app.YminSpinner.Step = 0.01;
            app.YminSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.YmaxSpinnerLabel = uilabel(app.ManualControlsTab);
            app.YmaxSpinnerLabel.Text = 'Ymax';
            app.YmaxSpinner = uispinner(app.ManualControlsTab);
            app.YmaxSpinner.Step = 0.01;
            app.YmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.InitialControlValueEditFieldLabel = uilabel(app.ManualControlsTab);
            app.InitialControlValueEditFieldLabel.Text = 'Initial Control Value';
            app.InitialControlValueEditField = uieditfield(app.ManualControlsTab, 'numeric');
            app.InitialControlValueEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.FinalControlValueEditFieldLabel = uilabel(app.ManualControlsTab);
            app.FinalControlValueEditFieldLabel.Text = 'Final Control Value';
            app.FinalControlValueEditField = uieditfield(app.ManualControlsTab, 'numeric');
            app.FinalControlValueEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.AddPointButton = uibutton(app.ManualControlsTab, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.DeletePointButton = uibutton(app.ManualControlsTab, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.SelectPointDropDownLabel = uilabel(app.ManualControlsTab);
            app.SelectPointDropDownLabel.Text = 'Select Point';
            app.SelectPointDropDown = uidropdown(app.ManualControlsTab);
            app.SelectPointDropDown.Items = {};
            app.SelectPointDropDown.Value = {};
            app.XminSpinnerLabel = uilabel(app.ManualControlsTab);
            app.XminSpinnerLabel.Text = 'Xmin';
            app.XminSpinner = uispinner(app.ManualControlsTab);
            app.XminSpinner.Step = 0.01;
            app.XminSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.XmaxSpinnerLabel = uilabel(app.ManualControlsTab);
            app.XmaxSpinnerLabel.Text = 'Xmax';
            app.XmaxSpinner = uispinner(app.ManualControlsTab);
            app.XmaxSpinner.Step = 0.01;
            app.XmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.LoadControlsDropDownLabel = uilabel(app.ManualControlsTab);
            app.LoadControlsDropDownLabel.Text = 'Load Controls';
            app.LoadControlsDropDown = uidropdown(app.ManualControlsTab);
            app.LoadControlsDropDown.Items = {};
            app.LoadControlsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LoadControlsDropDownOpening, true);
            app.LoadControlsDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadControlsDropDownValueChanged, true);
            app.LoadControlsDropDown.Value = {};
            app.SaveControlsButton = uibutton(app.ManualControlsTab, 'push');
            app.SaveControlsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveControlsButtonPushed, true);
            app.SaveControlsButton.Text = 'Save Controls';
            app.ControlsFolderButton = uibutton(app.ManualControlsTab, 'push');
            app.ControlsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ControlsFolderButtonPushed, true);
            app.ControlsFolderButton.Text = 'Controls Folder';
            app.MovingAverageWindowsEditFieldLabel = uilabel(app.ManualControlsTab);
            app.MovingAverageWindowsEditFieldLabel.Text = {'Moving Average'; 'Window [s]'};
            app.MovingAverageWindowsEditField = uieditfield(app.ManualControlsTab, 'numeric');
            app.MovingAverageWindowsEditField.Limits = [0.1 Inf];
            app.MovingAverageWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateControlsTab_ValueChanged, true);
            app.MovingAverageWindowsEditField.Value = 0.1;
            app.XSpinnerLabel = uilabel(app.ManualControlsTab);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.ManualControlsTab);
            app.YSpinnerLabel = uilabel(app.ManualControlsTab);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.ManualControlsTab);
            app.ControlFileNameEditFieldLabel = uilabel(app.ManualControlsTab);
            app.ControlFileNameEditFieldLabel.Text = 'Control File Name';
            app.ControlFileNameEditField = uieditfield(app.ManualControlsTab, 'text');
            app.UIAxes_ControlDesigner = uiaxes(app.ManualControlsTab);
            title(app.UIAxes_ControlDesigner, 'Control Designer')
            xlabel(app.UIAxes_ControlDesigner, 'Time [s]')
            ylabel(app.UIAxes_ControlDesigner, 'Y')
            zlabel(app.UIAxes_ControlDesigner, 'Z')
            app.TrimmedConditionsFlightDetailsTab = uitab(app.TabGroup4);
            app.TrimmedConditionsFlightDetailsTab.Title = 'Trimmed Conditions & Flight Details';
            app.TrimmedAltitudemEditFieldLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.TrimmedAltitudemEditFieldLabel.Text = 'Trimmed Altitude (m)';
            app.TrimmedAltitudemEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.TrimmedAltitudemEditField.Value = 500;
            app.TrimmedBearingEditFieldLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.TrimmedBearingEditFieldLabel.Text = 'Trimmed Bearing';
            app.TrimmedBearingEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.TrimmedAirspeedLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.TrimmedAirspeedLabel.Text = 'Trimmed Airspeed';
            app.TrimmedAirspeedEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.TrimmedAirspeedEditField.Value = 100;
            app.TrimmedSideslipAngledegEditFieldLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.TrimmedSideslipAngledegEditFieldLabel.Text = 'Trimmed Sideslip Angle (deg)';
            app.TrimmedSideslipAngledegEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.CentreofGravityPositionEditFieldLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.CentreofGravityPositionEditFieldLabel.Text = 'Centre of Gravity Position';
            app.CentreofGravityPositionEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.CentreofGravityPositionEditField.Value = 0.265;
            app.FlightPhaseCategoryDropDownLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.FlightPhaseCategoryDropDownLabel.Text = 'Flight Phase Category';
            app.FlightPhaseCategoryDropDown = uidropdown(app.TrimmedConditionsFlightDetailsTab);
            app.FlightPhaseCategoryDropDown.Items = {'A', 'B', 'C'};
            app.FlightPhaseCategoryDropDown.Value = 'A';
            app.InitialLatitudedegEditFieldLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.InitialLatitudedegEditFieldLabel.Text = 'Initial Latitude (deg)';
            app.InitialLatitudedegEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.InitialLatitudedegEditField.Value = -32.890219;
            app.InitialLongitudedegEditFieldLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.InitialLongitudedegEditFieldLabel.Text = 'Initial Longitude (deg)';
            app.InitialLongitudedegEditField = uieditfield(app.TrimmedConditionsFlightDetailsTab, 'numeric');
            app.InitialLongitudedegEditField.Value = 151.702862;
            app.TrimmedConditionsLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.TrimmedConditionsLabel.Text = 'Trimmed Conditions';
            app.ktsLabel_2 = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.ktsLabel_2.Text = 'kts';
            app.degTRUELabel_2 = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.degTRUELabel_2.Text = 'deg. TRUE';
            app.ftLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.ftLabel.Text = 'ft.';
            app.degLabel_9 = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.degLabel_9.Text = 'deg.';
            app.degNorthLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.degNorthLabel.Text = 'deg. North';
            app.degEastLabel = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.degEastLabel.Text = 'deg. East';
            app.perunitMACLabel_2 = uilabel(app.TrimmedConditionsFlightDetailsTab);
            app.perunitMACLabel_2.Text = 'per unit MAC';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = '       Simulation   ';
            app.RUNButton = uibutton(app.SimulationTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TimeSpanLabel = uilabel(app.SimulationTab);
            app.TimeSpanLabel.Text = 'Time Span';
            app.TimeSpanEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeSpanEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSpanStep_EditFieldValueChanged, true);
            app.TimeSpanEditField.Value = 30;
            app.TimeStepEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSpanStep_EditFieldValueChanged, true);
            app.TimeStepEditField.Value = 0.01;
            app.Label = uilabel(app.SimulationTab);
            app.Label.Text = '';
            app.PlotTrajectoryRequiresthemappingtoolboxCheckBox = uicheckbox(app.SimulationTab);
            app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotTrajectoryRequiresthemappingtoolboxCheckBoxValueChanged, true);
            app.PlotTrajectoryRequiresthemappingtoolboxCheckBox.Text = 'Plot Trajectory? (Requires the mapping toolbox)';
            app.AnimateRequirestheaerospacetoolboxCheckBox = uicheckbox(app.SimulationTab);
            app.AnimateRequirestheaerospacetoolboxCheckBox.Text = 'Animate? (Requires the aerospace toolbox)';
            app.TrajectoryDotsEditFieldLabel = uilabel(app.SimulationTab);
            app.TrajectoryDotsEditFieldLabel.Text = 'Trajectory Dots';
            app.TrajectoryDotsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TrajectoryDotsEditField.Value = 5;
            app.ModelDropDownLabel = uilabel(app.SimulationTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SimulationTab);
            app.ModelDropDown.Items = {'', '', '', ''};
            app.ModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelDropDownOpening, true);
            app.ModelDropDown.Value = '';
            app.secLabel = uilabel(app.SimulationTab);
            app.secLabel.Text = 'sec.';
            app.secLabel_2 = uilabel(app.SimulationTab);
            app.secLabel_2.Text = 'sec.';
            app.secLabel_3 = uilabel(app.SimulationTab);
            app.secLabel_3.Text = 'sec.';
            app.ImportkmlPathButton = uibutton(app.SimulationTab, 'push');
            app.ImportkmlPathButton.ButtonPushedFcn = createCallbackFcn(app, @ImportkmlPathButtonPushed, true);
            app.ImportkmlPathButton.Text = 'Import .kml Path';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = '       Results    ';
            app.TabGroup5 = uitabgroup(app.ResultsTab);
            app.TransientResponseTab = uitab(app.TabGroup5);
            app.TransientResponseTab.Title = 'Transient Response';
            app.TabGroup6 = uitabgroup(app.TransientResponseTab);
            app.StatesTab = uitab(app.TabGroup6);
            app.StatesTab.Title = 'States';
            app.uCheckBox = uicheckbox(app.StatesTab);
            app.uCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.uCheckBox.Text = 'u';
            app.uCheckBox.Value = true;
            app.vCheckBox = uicheckbox(app.StatesTab);
            app.vCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.vCheckBox.Text = 'v';
            app.vCheckBox.Value = true;
            app.wCheckBox = uicheckbox(app.StatesTab);
            app.wCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.wCheckBox.Text = 'w';
            app.wCheckBox.Value = true;
            app.pCheckBox = uicheckbox(app.StatesTab);
            app.pCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.pCheckBox.Text = 'p';
            app.pCheckBox.Value = true;
            app.qCheckBox = uicheckbox(app.StatesTab);
            app.qCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.qCheckBox.Text = 'q';
            app.qCheckBox.Value = true;
            app.rCheckBox = uicheckbox(app.StatesTab);
            app.rCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.rCheckBox.Text = 'r';
            app.rCheckBox.Value = true;
            app.phiCheckBox = uicheckbox(app.StatesTab);
            app.phiCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.phiCheckBox.Text = 'phi';
            app.phiCheckBox.Value = true;
            app.thetaCheckBox = uicheckbox(app.StatesTab);
            app.thetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.thetaCheckBox.Text = 'theta';
            app.thetaCheckBox.Value = true;
            app.psiCheckBox = uicheckbox(app.StatesTab);
            app.psiCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.psiCheckBox.Text = 'psi';
            app.psiCheckBox.Value = true;
            app.xCheckBox = uicheckbox(app.StatesTab);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.xCheckBox.Value = true;
            app.yCheckBox = uicheckbox(app.StatesTab);
            app.yCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.yCheckBox.Text = 'y';
            app.yCheckBox.Value = true;
            app.zCheckBox = uicheckbox(app.StatesTab);
            app.zCheckBox.ValueChangedFcn = createCallbackFcn(app, @Transient_CheckBoxValueChanged, true);
            app.zCheckBox.Text = 'z';
            app.zCheckBox.Value = true;
            app.UIAxes_1 = uiaxes(app.StatesTab);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.StatesTab);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.StatesTab);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.StatesTab);
            zlabel(app.UIAxes_4, 'Z')
            app.AerodynamicFrameTab = uitab(app.TabGroup6);
            app.AerodynamicFrameTab.Title = 'Aerodynamic Frame';
            app.UIAxes_13 = uiaxes(app.AerodynamicFrameTab);
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_14 = uiaxes(app.AerodynamicFrameTab);
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_15 = uiaxes(app.AerodynamicFrameTab);
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_15.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ControlsTab = uitab(app.TabGroup6);
            app.ControlsTab.Title = 'Controls';
            app.UIAxes_16 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_17 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_18 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_18, 'Z')
            app.UIAxes_19 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_19, 'Z')
            app.UIAxes_20 = uiaxes(app.ControlsTab);
            zlabel(app.UIAxes_20, 'Z')
            app.LinearAnalysisTab = uitab(app.TabGroup5);
            app.LinearAnalysisTab.Title = 'Linear Analysis';
            app.TabGroup7 = uitabgroup(app.LinearAnalysisTab);
            app.DynamicMatriciesTab = uitab(app.TabGroup7);
            app.DynamicMatriciesTab.Title = 'Dynamic Matricies';
            app.UITableA = uitable(app.DynamicMatriciesTab);
            app.UITableA.ColumnName = {'u'; 'v'; 'w'; 'p'; 'q'; 'r'; 'phi'; 'theta'; 'psi'; 'x'; 'y'; 'z'};
            app.UITableA.RowName = {'du'; 'dv'; 'dw'; 'dp'; 'dq'; 'dr'; 'dphi'; 'dtheta'; 'dpsi'; 'dx'; 'dy'; 'dz'};
            app.UITableB = uitable(app.DynamicMatriciesTab);
            app.UITableB.ColumnName = {'\delta_T'; '\delta_e'; '\delta_a'; '\delta_r'; '\delta_f'};
            app.UITableB.RowName = {'du'; 'dv'; 'dw'; 'dp'; 'dq'; 'dr'; 'dphi'; 'dtheta'; 'dpsi'; 'dx'; 'dy'; 'dz'};
            app.EigenAnalysisTab = uitab(app.TabGroup7);
            app.EigenAnalysisTab.Title = 'Eigen Analysis';
            app.UITableE1 = uitable(app.EigenAnalysisTab);
            app.UITableE1.ColumnName = {'Eigen Values'};
            app.UITableE1.RowName = {'Short Phugoid'; 'Long Phugoid'; 'Roll'; 'Dutch Roll'; 'Alt. conv./div.'; 'Spiral Roll'};
            app.UITableE2 = uitable(app.EigenAnalysisTab);
            app.UITableE2.ColumnName = {'Natural Frequency'; 'Damping Factor'};
            app.UITableE2.RowName = {};
            app.UIAxes_21 = uiaxes(app.EigenAnalysisTab);
            xlabel(app.UIAxes_21, 'Real')
            ylabel(app.UIAxes_21, 'Imaginary')
            zlabel(app.UIAxes_21, 'Z')
            app.ArgandDiagramsTab_2 = uitab(app.TabGroup7);
            app.ArgandDiagramsTab_2.Title = 'Argand Diagrams';
            app.ModeofMotionButtonGroup = uibuttongroup(app.ArgandDiagramsTab_2);
            app.ModeofMotionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeofMotionButtonGroupSelectionChanged, true);
            app.ModeofMotionButtonGroup.Title = 'Mode of Motion';
            app.SPButton = uiradiobutton(app.ModeofMotionButtonGroup);
            app.SPButton.Text = 'SP';
            app.SPButton.Value = true;
            app.LPButton = uiradiobutton(app.ModeofMotionButtonGroup);
            app.LPButton.Text = 'LP';
            app.RollButton_2 = uiradiobutton(app.ModeofMotionButtonGroup);
            app.RollButton_2.Text = 'Roll';
            app.DutchRollButton_2 = uiradiobutton(app.ModeofMotionButtonGroup);
            app.DutchRollButton_2.Text = 'Dutch Roll';
            app.AltcondivButton = uiradiobutton(app.ModeofMotionButtonGroup);
            app.AltcondivButton.Text = 'Alt con/div';
            app.SpiralRollButton = uiradiobutton(app.ModeofMotionButtonGroup);
            app.SpiralRollButton.Text = 'Spiral Roll';
            app.UITable_AD = uitable(app.ArgandDiagramsTab_2);
            app.UITable_AD.ColumnName = {'|x|'; '/_x (deg)'};
            app.UITable_AD.RowName = {};
            app.UIAxes_22 = uiaxes(app.ArgandDiagramsTab_2);
            zlabel(app.UIAxes_22, 'Z')
            app.MilitarySpecificationsTab = uitab(app.TabGroup5);
            app.MilitarySpecificationsTab.Title = 'Military Specifications';
            app.TabGroup9 = uitabgroup(app.MilitarySpecificationsTab);
            app.SPWnvszetaTab = uitab(app.TabGroup9);
            app.SPWnvszetaTab.Title = 'SP - Wn vs. zeta';
            app.UIAxes_30 = uiaxes(app.SPWnvszetaTab);
            title(app.UIAxes_30, 'Title')
            xlabel(app.UIAxes_30, 'X')
            ylabel(app.UIAxes_30, 'Y')
            zlabel(app.UIAxes_30, 'Z')
            app.SPWnvsdndalphaTab = uitab(app.TabGroup9);
            app.SPWnvsdndalphaTab.Title = 'SP - Wn vs. d(n)/d(alpha)';
            app.UIAxes_31 = uiaxes(app.SPWnvsdndalphaTab);
            title(app.UIAxes_31, 'Title')
            xlabel(app.UIAxes_31, 'X')
            ylabel(app.UIAxes_31, 'Y')
            zlabel(app.UIAxes_31, 'Z')
            app.DRTab = uitab(app.TabGroup9);
            app.DRTab.Title = 'DR';
            app.UIAxes_32 = uiaxes(app.DRTab);
            title(app.UIAxes_32, 'Title')
            xlabel(app.UIAxes_32, 'X')
            ylabel(app.UIAxes_32, 'Y')
            zlabel(app.UIAxes_32, 'Z')
            app.OptionsTab_3 = uitab(app.TabGroup5);
            app.OptionsTab_3.Title = 'Options';
            app.savetrajectoryasKMLfileLabel = uilabel(app.OptionsTab_3);
            app.savetrajectoryasKMLfileLabel.Text = 'save trajectory as KML file';
            app.KMLfilenameEditFieldLabel = uilabel(app.OptionsTab_3);
            app.KMLfilenameEditFieldLabel.Text = 'KML file name';
            app.KMLfilenameEditField = uieditfield(app.OptionsTab_3, 'text');
            app.SaveKMLfileButton = uibutton(app.OptionsTab_3, 'push');
            app.SaveKMLfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKMLfileButtonPushed, true);
            app.SaveKMLfileButton.Text = 'Save KML file';
            app.OptionsTab_2 = uitab(app.TabGroup);
            app.OptionsTab_2.Title = 'Options';
            app.CompareResultsupcomingLabel = uilabel(app.OptionsTab_2);
            app.CompareResultsupcomingLabel.Text = 'Compare Results (upcoming)';
            app.LoadSaveAllDataexceptresultsLabel = uilabel(app.OptionsTab_2);
            app.LoadSaveAllDataexceptresultsLabel.Text = 'Load & Save All Data (except results)';
            app.LoadFileButton = uibutton(app.OptionsTab_2, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.SaveFileButton = uibutton(app.OptionsTab_2, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.FileNameEditFieldLabel = uilabel(app.OptionsTab_2);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.OptionsTab_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end